classdef Designer_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        LeftPanel           matlab.ui.container.Panel
        AceptarButton       matlab.ui.control.StateButton
        ColorDropDown       matlab.ui.control.DropDown
        ColorDropDownLabel  matlab.ui.control.Label
        SealDropDown        matlab.ui.control.DropDown
        SealDropDownLabel   matlab.ui.control.Label
        RightPanel          matlab.ui.container.Panel
        UITable             matlab.ui.control.Table
        UIAxes              matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        x%function
        l% linspace
        color
        funct
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SealDropDown
        function SealDropDownValueChanged(app, event)
            value = app.SealDropDown.Value;
            if value==1
                app.l=linspace(0,3,3000);
                app.x=1*sin(k);
            end

        end

        % Value changed function: ColorDropDown
        function ColorDropDownValueChanged(app, event)
            value = app.ColorDropDown.Value;
            if value==1
                app.c='#FFFF00';
            end
      
        end

        % Value changed function: AceptarButton
        function AceptarButtonValueChanged(app, event)
            value = app.AceptarButton.Value;
            if value
                app.funct= app.SealDropDown.Value;
                col = app.ColorDropDown.Value;
                if col==1
                      app.color='#FFFF00';
                end
                if app.funct==1
                    app.l=linspace(0,3,3000);
                    app.x=sin(4*app.l);
                end
                    plot(app.l,app.x);
                    grid on;    
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SealDropDownLabel = uilabel(app.LeftPanel);
            app.SealDropDownLabel.Text = 'Señal';
            app.SealDropDown = uidropdown(app.LeftPanel);
            app.SealDropDown.ValueChangedFcn = createCallbackFcn(app, @SealDropDownValueChanged, true);
            app.ColorDropDownLabel = uilabel(app.LeftPanel);
            app.ColorDropDownLabel.Text = 'Color';
            app.ColorDropDown = uidropdown(app.LeftPanel);
            app.ColorDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorDropDownValueChanged, true);
            app.AceptarButton = uibutton(app.LeftPanel, 'state');
            app.AceptarButton.ValueChangedFcn = createCallbackFcn(app, @AceptarButtonValueChanged, true);
            app.AceptarButton.Text = 'Aceptar';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'Num_Variables'; 'Dimensionalidad'; 'Variables ind'; 'Valores'; 'Nat Estadistica'};
            app.UITable.RowName = {'1,2,3'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Designer_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end