classdef practica3_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        Tab                     matlab.ui.container.Tab
        AnchoEditField          matlab.ui.control.NumericEditField
        AnchoEditFieldLabel     matlab.ui.control.Label
        CicloEditField          matlab.ui.control.NumericEditField
        CicloEditFieldLabel     matlab.ui.control.Label
        PeriodosEditField       matlab.ui.control.NumericEditField
        PeriodosEditFieldLabel  matlab.ui.control.Label
        F0EditField             matlab.ui.control.NumericEditField
        F0EditFieldLabel        matlab.ui.control.Label
        FsEditField             matlab.ui.control.NumericEditField
        FsEditFieldLabel        matlab.ui.control.Label
        AmplitudEditField       matlab.ui.control.NumericEditField
        AmplitudEditFieldLabel  matlab.ui.control.Label
        TipoButtonGroup         matlab.ui.container.ButtonGroup
        AperidicaButton         matlab.ui.control.RadioButton
        PeridicaButton          matlab.ui.control.RadioButton
        SealDropDown            matlab.ui.control.DropDown
        SealDropDownLabel       matlab.ui.control.Label
        TiempoButtonGroup       matlab.ui.container.ButtonGroup
        DiscretoButton          matlab.ui.control.RadioButton
        ContinuoButton          matlab.ui.control.RadioButton
        GraficarButton          matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
        Tab2                    matlab.ui.container.Tab
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.AnchoEditField.Visible="off";
            app.AnchoEditFieldLabel.Visible="off";
 
        end

        % Callback function: GraficarButton, TiempoButtonGroup
        function GraficarButtonPushed(app, event)
         senal = app.SealDropDown.Value;
         if senal == 1  %Señal cuadrada
           
             A = app.AmplitudEditField.Value;
             Fs = app.FsEditField.Value;
             f = app.F0EditField.Value; 
             T = app.PeriodosEditField.Value * (1/f);
             duty = app.CicloEditField.Value;
             if app.PeridicaButton.Value == 1
             xlabel(app.UIAxes,"Tiempo (sec)")
            ylabel(app.UIAxes,"Amplitud")
            title(app.UIAxes,"Señal Cuadrada Periodica")
             t = 0:1/Fs:T-1/Fs; %Tiempo de simulación
             x = A*square(2*pi*f*t,duty);

             if app.ContinuoButton.Value == 1
                 plot(app.UIAxes,t,x)
                 grid(app.UIAxes,"on")
                 ylim(app.UIAxes,[-A A])
             end
             if app.DiscretoButton.Value ==1
                 stem(app.UIAxes,t,x)
                 grid(app.UIAxes,"on")
                 ylim(app.UIAxes,[-A A])
             end

             end
             
             if app.AperidicaButton.Value ==1  
            t = -100:1/Fs:100;
            x = A*rectpuls(t,f);
            xlabel(app.UIAxes,"Tiempo (sec)")
            ylabel(app.UIAxes,"Amplitud")
            title(app.UIAxes,"Señal Cuadrada Aperiodica")
            
             if app.ContinuoButton.Value == 1
                 plot(app.UIAxes,t,x)
                 grid(app.UIAxes,"on")
                 ylim(app.UIAxes,[-A A])
             end
             if app.DiscretoButton.Value ==1
                 stem(app.UIAxes,t,x)
                 grid(app.UIAxes,"on")
                 ylim(app.UIAxes,[-A A])
             end


             end
                
         end


         if senal == 2  %Diente de sierra
                 A = app.AmplitudEditField.Value;
                f = app.F0EditField.Value; %Frecuencia Fundamental
                Fs = app.FsEditField.Value; %Frecuencia de Muestreo
                T = app.PeriodosEditField.Value * (1/f);
                width = app.AnchoEditField.Value; %Ancho
             if app.PeridicaButton.Value ==1
             
                 %% Onda Diente de Sierra Tiempo Continuo
                 xlabel(app.UIAxes,"Tiempo (sec)")
                ylabel(app.UIAxes,"Amplitud")
                title(app.UIAxes,"Señal Diente de Sierra Periodica")
                
                t = 0:1/Fs:T-1/Fs; %Tiempo de Simulación
                x = A*sawtooth(2*pi*f*t,width); %Generación de Señal
                plot(app.UIAxes,t,x),grid(app.UIAxes,"on"),ylim(app.UIAxes,[-A A]) %Grafica
             if app.ContinuoButton.Value == 1
                 plot(app.UIAxes,t,x)
                 grid(app.UIAxes,"on")
                 ylim(app.UIAxes,[-A A])
             end
             if app.DiscretoButton.Value ==1
                 stem(app.UIAxes,t,x)
                 grid(app.UIAxes,"on")
                 ylim(app.UIAxes,[-A A])
             end

             end
             if app.AperidicaButton.Value ==1
            t = -100:1/Fs:100;
            x = A*tripuls(t,f);
            xlabel(app.UIAxes,"Tiempo (sec)")
            ylabel(app.UIAxes,"Amplitud")
            title(app.UIAxes,"Señal Cuadrada Aperiodica")
              if app.ContinuoButton.Value == 1
                 plot(app.UIAxes,t,x)
                 grid(app.UIAxes,"on")
                 ylim(app.UIAxes,[-A A])
             end
             if app.DiscretoButton.Value ==1
                 stem(app.UIAxes,t,x)
                 grid(app.UIAxes,"on")
                 ylim(app.UIAxes,[-A A])
             end


             end


         end

         if senal==5 %exponencial compleja
             A = app.AmplitudEditField.Value;
             Fs = app.FsEditField.Value; %muestras /segundo
             f = app.F0EditField.Value; %Frecuencia Fundamental
             T = app.PeriodosEditField.Value * (1/f)*(2*pi);
             e=2.71828;
             if app.PeridicaButton.Value ==1
                 xlabel(app.UIAxes,"Tiempo (sec)")
                 ylabel(app.UIAxes,"Amplitud")
                 title(app.UIAxes,"Señal exponencial compleja periodica(parte real)")
                 t = 0:1/Fs:T-1/Fs;%vector de tiempo, espaciado entre muestras y rango
                 x =A.*e.*exp(1i.*t);
                 plot(app.UIAxes,t,real(x)),grid(app.UIAxes,"on"),ylim(app.UIAxes,[-A A])

             end
             
             if app.AperidicaButton.Value==1
                t = 0:1/Fs:2*pi;
                x =A.*e.*exp(1i.*t);
                xlabel(app.UIAxes,"Tiempo (sec)")
                ylabel(app.UIAxes,"Amplitud")
                title(app.UIAxes,"Señal exponencial compleja aperiodica(parte real)")
                plot(app.UIAxes,t,real(x)),grid(app.UIAxes,"on"),ylim(app.UIAxes,[-A A])

             end

         end


        end

        % Value changed function: AnchoEditField, CicloEditField, 
        % ...and 1 other component
        function SealDropDownValueChanged(app, event)
            value = app.SealDropDown.Value;
            if value == 1
            app.AnchoEditField.Visible="off";
            app.AnchoEditFieldLabel.Visible="off";
            app.CicloEditField.Visible="on";
            app.CicloEditFieldLabel.Visible="on";
            end
            if value == 2
            app.AnchoEditField.Visible = "on";
            app.AnchoEditFieldLabel.Visible="on";

            app.CicloEditField.Visible="off";
            app.CicloEditFieldLabel.Visible="off";

            end 
               

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.GraficarButton = uibutton(app.Tab, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.TiempoButtonGroup = uibuttongroup(app.Tab);
            app.TiempoButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.TiempoButtonGroup.Title = 'Tiempo';
            app.ContinuoButton = uiradiobutton(app.TiempoButtonGroup);
            app.ContinuoButton.Text = 'Continuo';
            app.ContinuoButton.Value = true;
            app.DiscretoButton = uiradiobutton(app.TiempoButtonGroup);
            app.DiscretoButton.Text = 'Discreto';
            app.SealDropDownLabel = uilabel(app.Tab);
            app.SealDropDownLabel.Text = 'Señal';
            app.SealDropDown = uidropdown(app.Tab);
            app.SealDropDown.Items = {'Seleccione', 'Cuadrada', 'Diente de Sierra', 'Senoidal', 'Exponencial', 'E Compleja', 'Escalón', 'Impulso', 'Rampa', 'Rectangular', 'Triangular'};
            app.SealDropDown.ItemsData = [0 1 2 3 4 5 6 8 9 10];
            app.SealDropDown.ValueChangedFcn = createCallbackFcn(app, @SealDropDownValueChanged, true);
            app.SealDropDown.Value = 0;
            app.TipoButtonGroup = uibuttongroup(app.Tab);
            app.TipoButtonGroup.Title = 'Tipo';
            app.PeridicaButton = uiradiobutton(app.TipoButtonGroup);
            app.PeridicaButton.Text = 'Periódica';
            app.PeridicaButton.Value = true;
            app.AperidicaButton = uiradiobutton(app.TipoButtonGroup);
            app.AperidicaButton.Text = 'Aperiódica';
            app.AmplitudEditFieldLabel = uilabel(app.Tab);
            app.AmplitudEditFieldLabel.Text = 'Amplitud';
            app.AmplitudEditField = uieditfield(app.Tab, 'numeric');
            app.FsEditFieldLabel = uilabel(app.Tab);
            app.FsEditFieldLabel.Text = 'Fs';
            app.FsEditField = uieditfield(app.Tab, 'numeric');
            app.F0EditFieldLabel = uilabel(app.Tab);
            app.F0EditFieldLabel.Text = 'F0';
            app.F0EditField = uieditfield(app.Tab, 'numeric');
            app.PeriodosEditFieldLabel = uilabel(app.Tab);
            app.PeriodosEditFieldLabel.Text = 'Periodos';
            app.PeriodosEditField = uieditfield(app.Tab, 'numeric');
            app.CicloEditFieldLabel = uilabel(app.Tab);
            app.CicloEditFieldLabel.Text = 'Ciclo';
            app.CicloEditField = uieditfield(app.Tab, 'numeric');
            app.CicloEditField.ValueChangedFcn = createCallbackFcn(app, @SealDropDownValueChanged, true);
            app.AnchoEditFieldLabel = uilabel(app.Tab);
            app.AnchoEditFieldLabel.Text = 'Ancho';
            app.AnchoEditField = uieditfield(app.Tab, 'numeric');
            app.AnchoEditField.ValueChangedFcn = createCallbackFcn(app, @SealDropDownValueChanged, true);
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = practica3_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end