classdef practica_1_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        DropDown_2                    matlab.ui.control.DropDown
        Panel                         matlab.ui.container.Panel
        DropDown                      matlab.ui.control.DropDown
        EquipoFLabel                  matlab.ui.control.Label
        Prctica1Label                 matlab.ui.control.Label
        Image2                        matlab.ui.control.Image
        Image                         matlab.ui.control.Image
        EscuelaSuperiordeCmputoLabel  matlab.ui.control.Label
        INSTITUTOPOLITCNICONACIONALLabel  matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.DropDown_2.Visible = "off";
            app.UIAxes.Visible="off";
        end

        % Value changed function: DropDown, DropDown_2
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            value2 = app.DropDown_2.Value;
            t = linspace(0, 5, 1000); % Genera 1000 puntos en el intervalo [0, 5]

            if value == 1  %EJERCICIO 1
            app.Panel.Visible = "off";
            app.UIAxes.Visible = "on";
            app.DropDown_2.Visible = "on";
            a = 5*sin(2*t);     
            b = 3*cos(5*t);
            xlabel(app.UIAxes,"tiempo (t)")
            ylabel(app.UIAxes,"Amplitud")
                if value2 == 1
                    title(app.UIAxes,"5Sen2t")
                    plot(app.UIAxes,t,a)
                end
                if value2==2
                    title(app.UIAxes,"3Cos5t")
                    plot(app.UIAxes,t,b)
                end
                if value2==3
                    title(app.UIAxes,"a(t) x b(t)")
                    c = a.*b;
                    plot(app.UIAxes,t,c)
                end
                if value2==4
                    title(app.UIAxes,"a(t) + b(t)")
                    c = a+b;
                    plot(app.UIAxes,t,c)
                end
                if value2==5
                    title(app.UIAxes,"a(t) - b(t)")
                    c = a-b;
                    plot(app.UIAxes,t,c)
                end
            end

            if value == 2   %EJERCICIO 2
             xlabel(app.UIAxes,"tiempo (t)")
            ylabel(app.UIAxes,"Amplitud")
            app.Panel.Visible = "off";
            app.UIAxes.Visible = "on";
            app.DropDown_2.Visible = "on";
            a = 25*sin(2*t);     
            b = 3*cos(5*t);
                if value2 == 1
                    title(app.UIAxes,"25Sen2t")
                    plot(app.UIAxes,t,a)
                end
                if value2==2
                    title(app.UIAxes,"3Cos5t")
                    plot(app.UIAxes,t,b)
                end
                if value2==3
                    title(app.UIAxes,"a(t) x b(t)")
                    c = a.*b;
                    plot(app.UIAxes,t,c)
                end
                if value2==4
                    title(app.UIAxes,"a(t) + b(t)")
                    c = a+b;
                    plot(app.UIAxes,t,c)
                end
                if value2==5
                    title(app.UIAxes,"a(t) - b(t)")
                    c = a-b;
                    plot(app.UIAxes,t,c)
                end
            end 
            if value == 3      %EJERCICIO 3
             xlabel(app.UIAxes,"tiempo (t)")
            ylabel(app.UIAxes,"Amplitud")
            app.Panel.Visible = "off";
            app.UIAxes.Visible = "on";
            app.DropDown_2.Visible = "off";
             t = 0:0.001:1; % vector que toma valores cada 1 ms 
             x = sin(40*t) + sin(80*t);
            plot(app.UIAxes,t,x)
            title(app.UIAxes,"x(t)")
                
            end
            if value ==4        %EJERCICIO 4
            app.Panel.Visible = "on";
            app.UIAxes.Visible = "off";
            app.DropDown_2.Visible = "off";
                t = 0:0.001:1; % vector que toma valores cada 1 ms 
                t2 = 0:0.1:1; 
                x = sin(40*t) + sin(80*t);
                x2 = sin(40*t2) + sin(80*t2); % Señal ahora con t2
            app.Panel.AutoResizeChildren = 'off';
            ax1 = subplot(1,2,1,'Parent',app.Panel);
            ax2 = subplot(1,2,2,'Parent',app.Panel);
            plot(ax1,t,x)
            plot(ax2,t2,x2)
            end
            if value ==5       %EJERCICIO 5
                      xlabel(app.UIAxes,"tiempo (t)")
            ylabel(app.UIAxes,"Amplitud")
            app.Panel.Visible = "off";
            app.UIAxes.Visible = "on";
            app.DropDown_2.Visible = "off";
             t = 0:0.001:1; % vector que toma valores cada 1 ms 
             x = sin(80*t) + sin(160*t);
            plot(app.UIAxes,t,x)
            title(app.UIAxes,"x(t) con amplitud x2")

            end
            if value == 6   %EJERCICIO 6
             xlabel(app.UIAxes,"tiempo (t)")
            ylabel(app.UIAxes,"Amplitud")
            app.Panel.Visible = "off";
            app.UIAxes.Visible = "on";
            app.DropDown_2.Visible = "off";
             t = 0:0.001:1; % vector que toma valores cada 1 ms 
             x = sin(400*t) + sin(800*t);
            plot(app.UIAxes,t,x)
            title(app.UIAxes,"x(t) con amplitud x10")

            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.INSTITUTOPOLITCNICONACIONALLabel = uilabel(app.UIFigure);
            app.INSTITUTOPOLITCNICONACIONALLabel.Text = 'INSTITUTO POLITÉCNICO NACIONAL ';
            app.EscuelaSuperiordeCmputoLabel = uilabel(app.UIFigure);
            app.EscuelaSuperiordeCmputoLabel.Text = 'Escuela Superior de Cómputo';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'logo_ipn-removebg-preview.png';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'logoESCOM2x.png';
            app.Prctica1Label = uilabel(app.UIFigure);
            app.Prctica1Label.Text = 'Práctica 1';
            app.EquipoFLabel = uilabel(app.UIFigure);
            app.EquipoFLabel.Text = 'Equipo F';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Selecciona', 'Ejercicio 1', 'Ejercicio 2', 'Ejercicio 3', 'Ejercicio 4', 'Ejercicio 5', 'Ejercicio 6'};
            app.DropDown.ItemsData = [0 1 2 3 4 5 6 7];
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 0;
            app.Panel = uipanel(app.UIFigure);
            app.DropDown_2 = uidropdown(app.UIFigure);
            app.DropDown_2.Items = {'Selecciona ', 'a', 'b', 'c', 'd', 'e'};
            app.DropDown_2.ItemsData = [0 1 2 3 4 5];
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown_2.Value = 0;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = practica_1_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end