classdef practica3_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        Tab                     matlab.ui.container.Tab
        SealDropDown            matlab.ui.control.DropDown
        SealDropDownLabel       matlab.ui.control.Label
        Knob                    matlab.ui.control.DiscreteKnob
        Switch                  matlab.ui.control.Switch
        BaseEditField           matlab.ui.control.NumericEditField
        BaseEditFieldLabel      matlab.ui.control.Label
        DesfaseEditField        matlab.ui.control.NumericEditField
        DesfaseEditFieldLabel   matlab.ui.control.Label
        AnchoEditField          matlab.ui.control.NumericEditField
        AnchoEditFieldLabel     matlab.ui.control.Label
        CicloEditField          matlab.ui.control.NumericEditField
        CicloEditFieldLabel     matlab.ui.control.Label
        PeriodosEditField       matlab.ui.control.NumericEditField
        PeriodosEditFieldLabel  matlab.ui.control.Label
        F0EditField             matlab.ui.control.NumericEditField
        F0EditFieldLabel        matlab.ui.control.Label
        FsEditField             matlab.ui.control.NumericEditField
        FsEditFieldLabel        matlab.ui.control.Label
        AmplitudEditField       matlab.ui.control.NumericEditField
        AmplitudEditFieldLabel  matlab.ui.control.Label
        TipoButtonGroup         matlab.ui.container.ButtonGroup
        AperidicaButton         matlab.ui.control.RadioButton
        PeridicaButton          matlab.ui.control.RadioButton
        TiempoButtonGroup       matlab.ui.container.ButtonGroup
        DiscretoButton          matlab.ui.control.RadioButton
        ContinuoButton          matlab.ui.control.RadioButton
        GraficarButton          matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
        Tab2                    matlab.ui.container.Tab
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.AnchoEditField.Visible="off";
            app.AnchoEditFieldLabel.Visible="off";

            app.DesfaseEditField.Visible="off";
            app.DesfaseEditFieldLabel.Visible="off";
            
            app.BaseEditField.Visible="off";
            app.BaseEditFieldLabel.Visible="off";
            
            app.Switch.Visible="off";
            app.Knob.Visible ="off";

        end

        % Callback function: GraficarButton, TiempoButtonGroup
        function GraficarButtonPushed(app, event)
         senal = app.SealDropDown.Value;
         if senal == 1  %Señal cuadrada
           
             A = app.AmplitudEditField.Value;
             Fs = app.FsEditField.Value;
             f = app.F0EditField.Value; %%f0
             T = app.PeriodosEditField.Value * (1/f);
             duty = app.CicloEditField.Value;
             if app.PeridicaButton.Value == 1
             xlabel(app.UIAxes,"Tiempo (sec)")
            ylabel(app.UIAxes,"Amplitud")
            title(app.UIAxes,"Señal Cuadrada Periodica")
             t = 0:1/Fs:T-1/Fs; %Tiempo de simulación
             x = A*square(2*pi*f*t,duty);

             if app.ContinuoButton.Value == 1
                 plot(app.UIAxes,t,x)
                 grid(app.UIAxes,"on")
                 ylim(app.UIAxes,[-A A])
             end
             if app.DiscretoButton.Value ==1
                 stem(app.UIAxes,t,x)
                 grid(app.UIAxes,"on")
                 ylim(app.UIAxes,[-A A])
             end

             end
             
             if app.AperidicaButton.Value ==1  
            t = -100:1/Fs:100;
            x = A*rectpuls(t,f);
            xlabel(app.UIAxes,"Tiempo (sec)")
            ylabel(app.UIAxes,"Amplitud")
            title(app.UIAxes,"Señal Cuadrada Aperiodica")
            
             if app.ContinuoButton.Value == 1
                 plot(app.UIAxes,t,x)
                 grid(app.UIAxes,"on")
                 ylim(app.UIAxes,[-A A])
             end
             if app.DiscretoButton.Value ==1
                 stem(app.UIAxes,t,x)
                 grid(app.UIAxes,"on")
                 ylim(app.UIAxes,[-A A])
             end


             end
                
         end


         if senal == 2  %Diente de sierra
                 A = app.AmplitudEditField.Value;
                f = app.F0EditField.Value; %Frecuencia Fundamental
                Fs = app.FsEditField.Value; %Frecuencia de Muestreo
                T = app.PeriodosEditField.Value * (1/f);
                width = app.AnchoEditField.Value; %Ancho
             if app.PeridicaButton.Value ==1
                 %% Onda Diente de Sierra Tiempo Continuo
                 xlabel(app.UIAxes,"Tiempo (sec)")
                ylabel(app.UIAxes,"Amplitud")
                title(app.UIAxes,"Señal Diente de Sierra Periodica")
                
                t = 0:1/Fs:T-1/Fs; %Tiempo de Simulación
                x = A*sawtooth(2*pi*f*t,width); %Generación de Señal
                plot(app.UIAxes,t,x),grid(app.UIAxes,"on"),ylim(app.UIAxes,[-A A]) %Grafica
             if app.ContinuoButton.Value == 1
                 plot(app.UIAxes,t,x)
                 grid(app.UIAxes,"on")
                 ylim(app.UIAxes,[-A A])
             end
             if app.DiscretoButton.Value ==1
                 stem(app.UIAxes,t,x)
                 grid(app.UIAxes,"on")
                 ylim(app.UIAxes,[-A A])
             end
             end
             if app.AperidicaButton.Value ==1
            t = -100:1/Fs:100;
            x = A*tripuls(t,f);
            xlabel(app.UIAxes,"Tiempo (sec)")
            ylabel(app.UIAxes,"Amplitud")
            title(app.UIAxes,"Señal Diente de Sierra Aperiodica")
              if app.ContinuoButton.Value == 1
                 plot(app.UIAxes,t,x)
                 grid(app.UIAxes,"on")
                 ylim(app.UIAxes,[-A A])
             end
             if app.DiscretoButton.Value ==1
                 stem(app.UIAxes,t,x)
                 grid(app.UIAxes,"on")
                 ylim(app.UIAxes,[-A A])
             end


             end


         end

         if senal ==3 %Señal Senoidal
                A = app.AmplitudEditField.Value;
                f = app.F0EditField.Value; %Frecuencia Fundamental
                Fs = app.FsEditField.Value; %Frecuencia de Muestreo
                T = app.PeriodosEditField.Value * (1/f);
                des = app.DesfaseEditField.Value; %Desfase
                phi = deg2rad(des);
                if app.PeridicaButton.Value ==1
                t = 0:1/Fs:T-1/Fs; %Tiempo de Simulación
                x = A*sin(2*pi*f*t+phi); %Generación de Señal
                xlabel(app.UIAxes,"Tiempo (sec)")
                ylabel(app.UIAxes,"Amplitud")
                title(app.UIAxes,"Señal Senoidal Periodica")           

                plot(app.UIAxes,t,x),grid(app.UIAxes,"on"),ylim(app.UIAxes,[-A A]) %Grafica
                 if app.ContinuoButton.Value == 1
                 plot(app.UIAxes,t,x)
                 grid(app.UIAxes,"on")
                 ylim(app.UIAxes,[-A A])
                end
                 if app.DiscretoButton.Value ==1
                     stem(app.UIAxes,t,x)
                     grid(app.UIAxes,"on")
                     ylim(app.UIAxes,[-A A])
                 end
                    
                end
                  if app.AperidicaButton.Value ==1
                t = linspace(-f,f);
                x = A* sinc(t);
                xlabel(app.UIAxes,"Tiempo (sec)")
                ylabel(app.UIAxes,"Amplitud")
                title(app.UIAxes,"Señal Senoidal Aperiodica")
                  if app.ContinuoButton.Value == 1
                     plot(app.UIAxes,t,x)
                     grid(app.UIAxes,"on")
                     ylim(app.UIAxes,[-A A])
                 end
                 if app.DiscretoButton.Value ==1
                     stem(app.UIAxes,t,x)
                     grid(app.UIAxes,"on")
                     ylim(app.UIAxes,[-A A])
                 end
                  end
         end

         if senal == 4
             cla(app.UIAxes)
             ylim(app.UIAxes,[-inf inf])
             Fs = app.FsEditField.Value;
             t = 0:1/Fs:10;
             if app.Switch.Value == 0
                 x1 = exp(t);
                  xlabel(app.UIAxes,"Tiempo (sec)")
                ylabel(app.UIAxes,"Amplitud")
                title(app.UIAxes,"Señal Exponencial Creciente")
                if app.ContinuoButton.Value==1
                plot(app.UIAxes,t,x1)
                end
                if app.DiscretoButton.Value==1
                stem(app.UIAxes,t,x1)
                end
             end
            
             if app.Switch.Value == 1
                       xlabel(app.UIAxes,"Tiempo (sec)")
                ylabel(app.UIAxes,"Amplitud")
                title(app.UIAxes,"Señal Exponencial Decreciente")
             r = app.BaseEditField.Value;
             x2 = r.^(-t); %Generación de Señal
                if app.ContinuoButton.Value==1
                plot(app.UIAxes,t,x2,Color="red")
                end
                if app.DiscretoButton.Value==1
                stem(app.UIAxes,t,x2,Color="red")
                end
             end
         end

         if senal == 5
             A = app.AmplitudEditField.Value;
             Fs = app.FsEditField.Value;
             t = -1.5*pi:1/Fs:1.5*pi; %Tiempo de Simulación
            x = A *exp(1j.*(2.*t+(pi/4))); %Generación de Señal
            xRE = real(x); %Cálculo de la parte Real de x(t)
            xIM = imag(x); %Cálculo de la parte Imaginaria de x(t)
            xMAG = abs(x); %Cálculo de la Magnitud de x(t)
            xARG = atan(imag(x)./real(x)); %Cálculo del Argumento de x(t)
            if app.Knob.Value == 0
                xlabel(app.UIAxes,"Tiempo (sec)")
                ylabel(app.UIAxes,"Amplitud")
                title(app.UIAxes,"Parte real de x(t)")
                if app.ContinuoButton.Value==1
                     plot(app.UIAxes,t,xRE)
                
                end
                if app.DiscretoButton.Value==1
                     stem(app.UIAxes,t,xRE)
                
                end
               
            end
            if app.Knob.Value ==1
                 xlabel(app.UIAxes,"Tiempo (sec)")
                ylabel(app.UIAxes,"Amplitud")
                title(app.UIAxes,"Parte Im de x(t)")
                if app.ContinuoButton.Value==1
                     plot(app.UIAxes,t,xIM)
                end
                if app.DiscretoButton.Value==1
                     stem(app.UIAxes,t,xIM)
                end
                
              
            end
            
            if app.Knob.Value ==2
                xlabel(app.UIAxes,"Tiempo (sec)")
                ylabel(app.UIAxes,"Amplitud")
                title(app.UIAxes,"Magnitud de x(t)")
                 if app.ContinuoButton.Value==1
                      plot(app.UIAxes,t,xMAG)
                end
                if app.DiscretoButton.Value==1
                     stem(app.UIAxes,t,xMAG)
                end
                   

            end
            
            if app.Knob.Value == 3
                 xlabel(app.UIAxes,"Tiempo (sec)")
                ylabel(app.UIAxes,"Amplitud")
                title(app.UIAxes,"Argumento de x(t)")
                 if app.ContinuoButton.Value==1
                      plot(app.UIAxes,t,xARG)
                end
                if app.DiscretoButton.Value==1
                     stem(app.UIAxes,t,xARG)
                end
           end
    
         end

         if senal == 6
              
             A = app.AmplitudEditField.Value;
             Fs = app.FsEditField.Value;
             t = 0:1/Fs:10; %Tiempo de Simulación
             x = A.*ones(1,length(t));
             if app.ContinuoButton.Value == 1
             plot(app.UIAxes,t,x)
             end
             if app.DiscretoButton.Value ==1
             stem(app.UIAxes,t,x)
             end

             if app.PeridicaButton.Value == 1
                xlabel(app.UIAxes,"Tiempo (sec)")
                ylabel(app.UIAxes,"Amplitud")
                title(app.UIAxes,"Señal Escalón Periódica")
                sqwave = square(4*pi*t);
                plot(app.UIAxes,t,sqwave)
             end
             if app.AperidicaButton.Value == 1
                 xlabel(app.UIAxes,"Tiempo (sec)")
                ylabel(app.UIAxes,"Amplitud")
                title(app.UIAxes,"Señal Escalón Aperiódica")
             end

         end

         if senal == 7
              xlabel(app.UIAxes,'Tiempo');
                ylabel(app.UIAxes,'Amplitud');
                title(app.UIAxes,'Impulso Unitario');
             A = app.AmplitudEditField.Value;
             n = 0:5; 
             x = A.*[zeros(1,3),ones(1,1),zeros(1,2)]; %Generación de Señal
             stem(app.UIAxes,n,x)

             if app.PeridicaButton.Value ==1
                 % Parámetros del tren de impulsos
                T = 5; % Período del tren de impulsos (número de muestras por período)
                N = 50; % Número total de muestras
                
                % Inicializa un vector de ceros
                tren_impulsos = zeros(1, N);
                
                % Crea el tren de impulsos
                for i = 1:N
                    if mod(i, T) == 0
                        tren_impulsos(i) = 1;
                    end
                end
                
                % Visualiza el tren de impulsos
                stem(app.UIAxes,tren_impulsos);
                xlabel(app.UIAxes,'Muestras');
                ylabel(app.UIAxes,'Amplitud');
                title(app.UIAxes,'Tren de Impulsos Unitarios Periódicos');

             end
         end

         if senal == 8
                              xlabel(app.UIAxes,"Tiempo (sec)")
                           grid(app.UIAxes,"on")
                ylabel(app.UIAxes,"Amplitud")
                title(app.UIAxes,"Señal Rampa")
             A = app.AmplitudEditField.Value; 
             Fs = app.FsEditField.Value;
             t1 = 0:1/Fs:5; t2 = 5:1/Fs:10;r = t1;
             t = [t1,t2]; %Tiempo de Simulación
             x = A.*[zeros(1,length(t1)),r]; %Generación de Señal
             if app.ContinuoButton.Value==1

                 plot(app.UIAxes,t,x)   
                
             end
             if app.DiscretoButton.Value==1
                 stem(app.UIAxes,t,x)   
                

             end
             
         end

         if senal == 9
             xlabel(app.UIAxes,"Tiempo (sec)")
                           grid(app.UIAxes,"on")
                ylabel(app.UIAxes,"Amplitud")
                title(app.UIAxes,"Señal Rectangular")
            A =app.AmplitudEditField.Value;
            fs = app.FsEditField.Value;
            t = -0.1:1/fs:0.1;
            w = app.AnchoEditField.Value;          
            x = A* rectpuls(t,2000);
            axis(app.UIAxes,[0 inf 0 inf])
            if app.ContinuoButton.Value ==1
                plot(app.UIAxes,t,x)
            end
            
            if app.DiscretoButton.Value == 1
                stem(app.UIAxes,t,x)
            end

             if app.PeridicaButton.Value == 1
                 fs = 100e9;
                D = [2.5 10 17.5]' * 1e-9;
                t = 0 : 1/fs : 2500/fs;
                w = 1e-9;
                yp = pulstran(t,D,@rectpuls,w);
                plot(app.UIAxes,t*1e9,yp)
                axis(app.UIAxes,[0 25 -0.2 1.2])
                xlabel(app.UIAxes,"Time (ns)")
                ylabel(app.UIAxes,"Amplitud")
                title(app.UIAxes,"Señal rectangular periódica")
                
             end           
         end
    
         if senal == 10
             xlabel(app.UIAxes,"Time (sec)")
            ylabel(app.UIAxes,"Amplitud")
            A = app.AmplitudEditField.Value;
            fs = app.FsEditField.Value;
            t = -1:1/fs:1;
            w = app.AnchoEditField.Value;
            x1 =A * tripuls(t,w);
            if app.AperidicaButton.Value == 1
                title(app.UIAxes,"Señal Triangular")
                plot(app.UIAxes,t,x1);
            end
           
            if app.PeridicaButton.Value == 1
                 title(app.UIAxes,"Señal Triangular Periódica")
        T = 10*(1/50);
        
        fs = 1000;
        t = 0:1/fs:T-1/fs;
        
        x = sawtooth(2*pi*50*t,1/2);
        
        plot(app.UIAxes,t,x)
        grid on
           end


         end


        end

        % Value changed function: AnchoEditField, CicloEditField, 
        % ...and 1 other component
        function SealDropDownValueChanged(app, event)
         

        end

        % Value changed function: SealDropDown
        function SealDropDownValueChanged2(app, event)
               value = app.SealDropDown.Value;
            if value == 1
             app.Knob.Visible ="off";
                         
            app.Switch.Visible="off";

             app.AmplitudEditField.Visible="on";
            app.AmplitudEditFieldLabel.Visible="on";

            app.F0EditField.Visible="on";
            app.F0EditFieldLabel.Visible="on";

            app.PeriodosEditField.Visible="on";
            app.PeriodosEditFieldLabel.Visible="on";
            
            app.AnchoEditField.Visible="off";
            app.AnchoEditFieldLabel.Visible="off";


            app.CicloEditField.Visible="on";
            app.CicloEditFieldLabel.Visible="on";

             app.DesfaseEditField.Visible="off";
            app.DesfaseEditFieldLabel.Visible="off";

            app.BaseEditField.Visible="off";
            app.BaseEditFieldLabel.Visible="off";
            end
            if value == 2
                 app.Knob.Visible ="off";
                       
            app.Switch.Visible="off";
            app.AmplitudEditField.Visible="on";
            app.AmplitudEditFieldLabel.Visible="on";

            app.F0EditField.Visible="on";
            app.F0EditFieldLabel.Visible="on";

            app.PeriodosEditField.Visible="on";
            app.PeriodosEditFieldLabel.Visible="on";
            

            app.AnchoEditField.Visible = "on";
            app.AnchoEditFieldLabel.Visible="on";

            app.CicloEditField.Visible="off";
            app.CicloEditFieldLabel.Visible="off";

             app.DesfaseEditField.Visible="off";
            app.DesfaseEditFieldLabel.Visible="off";
            
            app.BaseEditField.Visible="off";
            app.BaseEditFieldLabel.Visible="off";
            end 
            if value == 3
             app.Knob.Visible ="off";
                           
            app.Switch.Visible="off";
            app.AmplitudEditField.Visible="on";
            app.AmplitudEditFieldLabel.Visible="on";

            app.F0EditField.Visible="on";
            app.F0EditFieldLabel.Visible="on";

            app.PeriodosEditField.Visible="on";
            app.PeriodosEditFieldLabel.Visible="on";
            

            app.DesfaseEditField.Visible="on";
            app.DesfaseEditFieldLabel.Visible="on";


            app.CicloEditField.Visible="off";
            app.CicloEditFieldLabel.Visible="off";

              app.AnchoEditField.Visible="off";
            app.AnchoEditFieldLabel.Visible="off";
                
            app.BaseEditField.Visible="off";
            app.BaseEditFieldLabel.Visible="off";
            end

            if value == 4
             app.Knob.Visible ="off";
                           
            app.Switch.Visible="on";

            app.BaseEditField.Visible="on";
            app.BaseEditFieldLabel.Visible="on";
             
            app.AmplitudEditField.Visible="off";
            app.AmplitudEditFieldLabel.Visible="off";

            app.F0EditField.Visible="off";
            app.F0EditFieldLabel.Visible="off";

            app.PeriodosEditField.Visible="off";
            app.PeriodosEditFieldLabel.Visible="off";
            

            app.CicloEditField.Visible="off";
            app.CicloEditFieldLabel.Visible="off";

            app.AnchoEditField.Visible="off";
            app.AnchoEditFieldLabel.Visible="off";

            app.DesfaseEditField.Visible="off";
            app.DesfaseEditFieldLabel.Visible="off";
            end 

            if value == 5
            app.Knob.Visible ="on";
            app.Switch.Visible="off";
              app.DesfaseEditField.Visible="off";
            app.DesfaseEditFieldLabel.Visible="off";

            app.BaseEditField.Visible="off";
            app.BaseEditFieldLabel.Visible="off";

            app.FsEditField.Visible="on";
            app.FsEditFieldLabel.Visible="on";

              app.AmplitudEditField.Visible="on";
            app.AmplitudEditFieldLabel.Visible="on";

            app.F0EditField.Visible="off";
            app.F0EditFieldLabel.Visible="off";

            app.PeriodosEditField.Visible="off";
            app.PeriodosEditFieldLabel.Visible="off";
            

            app.CicloEditField.Visible="off";
            app.CicloEditFieldLabel.Visible="off";

            app.AnchoEditField.Visible="off";
            app.AnchoEditFieldLabel.Visible="off";           
            end

            if value == 6
            app.Knob.Visible ="off";
            app.Switch.Visible="off";   app.DesfaseEditField.Visible="off";
            app.DesfaseEditFieldLabel.Visible="off";

            app.BaseEditField.Visible="off";
            app.BaseEditFieldLabel.Visible="off";

            app.FsEditField.Visible="on";
            app.FsEditFieldLabel.Visible="on";

              app.AmplitudEditField.Visible="on";
            app.AmplitudEditFieldLabel.Visible="on";

            app.F0EditField.Visible="off";
            app.F0EditFieldLabel.Visible="off";

            app.PeriodosEditField.Visible="off";
            app.PeriodosEditFieldLabel.Visible="off";
            

            app.CicloEditField.Visible="off";
            app.CicloEditFieldLabel.Visible="off";

            app.AnchoEditField.Visible="off";
            app.AnchoEditFieldLabel.Visible="off"; 

            end

            if value == 7
                 app.Knob.Visible ="off";
            app.Switch.Visible="off";   
            app.DesfaseEditField.Visible="off";
            app.DesfaseEditFieldLabel.Visible="off";

            app.BaseEditField.Visible="off";
            app.BaseEditFieldLabel.Visible="off";

            app.FsEditField.Visible="off";
            app.FsEditFieldLabel.Visible="off";

              app.AmplitudEditField.Visible="on";
            app.AmplitudEditFieldLabel.Visible="on";

            app.F0EditField.Visible="off";
            app.F0EditFieldLabel.Visible="off";

            app.PeriodosEditField.Visible="off";
            app.PeriodosEditFieldLabel.Visible="off";
            

            app.CicloEditField.Visible="off";
            app.CicloEditFieldLabel.Visible="off";

            app.AnchoEditField.Visible="off";
            app.AnchoEditFieldLabel.Visible="off"; 

            end

            if value == 8
                   app.Knob.Visible ="off";
            app.Switch.Visible="off";   
            app.DesfaseEditField.Visible="off";
            app.DesfaseEditFieldLabel.Visible="off";

            app.BaseEditField.Visible="off";
            app.BaseEditFieldLabel.Visible="off";

            app.FsEditField.Visible="on";
            app.FsEditFieldLabel.Visible="on";

              app.AmplitudEditField.Visible="on";
            app.AmplitudEditFieldLabel.Visible="on";

            app.F0EditField.Visible="off";
            app.F0EditFieldLabel.Visible="off";

            app.PeriodosEditField.Visible="off";
            app.PeriodosEditFieldLabel.Visible="off";
            

            app.CicloEditField.Visible="off";
            app.CicloEditFieldLabel.Visible="off";

            app.AnchoEditField.Visible="off";
            app.AnchoEditFieldLabel.Visible="off"; 
            end

            if value == 9 
                 app.Knob.Visible ="off";
            app.Switch.Visible="off";   
            app.DesfaseEditField.Visible="off";
            app.DesfaseEditFieldLabel.Visible="off";

            app.BaseEditField.Visible="off";
            app.BaseEditFieldLabel.Visible="off";

            app.FsEditField.Visible="on";
            app.FsEditFieldLabel.Visible="on";

              app.AmplitudEditField.Visible="on";
            app.AmplitudEditFieldLabel.Visible="on";

            app.F0EditField.Visible="off";
            app.F0EditFieldLabel.Visible="off";

            app.PeriodosEditField.Visible="off";
            app.PeriodosEditFieldLabel.Visible="off";
            

            app.CicloEditField.Visible="off";
            app.CicloEditFieldLabel.Visible="off";

            app.AnchoEditField.Visible="on";
            app.AnchoEditFieldLabel.Visible="on"; 

            end
            if value == 10
                 app.Knob.Visible ="off";
            app.Switch.Visible="off";   
            app.DesfaseEditField.Visible="off";
            app.DesfaseEditFieldLabel.Visible="off";

            app.BaseEditField.Visible="off";
            app.BaseEditFieldLabel.Visible="off";

            app.FsEditField.Visible="on";
            app.FsEditFieldLabel.Visible="on";

              app.AmplitudEditField.Visible="on";
            app.AmplitudEditFieldLabel.Visible="on";

            app.F0EditField.Visible="off";
            app.F0EditFieldLabel.Visible="off";

            app.PeriodosEditField.Visible="off";
            app.PeriodosEditFieldLabel.Visible="off";
            

            app.CicloEditField.Visible="off";
            app.CicloEditFieldLabel.Visible="off";

            app.AnchoEditField.Visible="on";
            app.AnchoEditFieldLabel.Visible="on"; 

            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.GraficarButton = uibutton(app.Tab, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.TiempoButtonGroup = uibuttongroup(app.Tab);
            app.TiempoButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.TiempoButtonGroup.Title = 'Tiempo';
            app.ContinuoButton = uiradiobutton(app.TiempoButtonGroup);
            app.ContinuoButton.Text = 'Continuo';
            app.ContinuoButton.Value = true;
            app.DiscretoButton = uiradiobutton(app.TiempoButtonGroup);
            app.DiscretoButton.Text = 'Discreto';
            app.TipoButtonGroup = uibuttongroup(app.Tab);
            app.TipoButtonGroup.Title = 'Tipo';
            app.PeridicaButton = uiradiobutton(app.TipoButtonGroup);
            app.PeridicaButton.Text = 'Periódica';
            app.PeridicaButton.Value = true;
            app.AperidicaButton = uiradiobutton(app.TipoButtonGroup);
            app.AperidicaButton.Text = 'Aperiódica';
            app.AmplitudEditFieldLabel = uilabel(app.Tab);
            app.AmplitudEditFieldLabel.Text = 'Amplitud';
            app.AmplitudEditField = uieditfield(app.Tab, 'numeric');
            app.FsEditFieldLabel = uilabel(app.Tab);
            app.FsEditFieldLabel.Text = 'Fs';
            app.FsEditField = uieditfield(app.Tab, 'numeric');
            app.F0EditFieldLabel = uilabel(app.Tab);
            app.F0EditFieldLabel.Text = 'F0';
            app.F0EditField = uieditfield(app.Tab, 'numeric');
            app.PeriodosEditFieldLabel = uilabel(app.Tab);
            app.PeriodosEditFieldLabel.Text = 'Periodos';
            app.PeriodosEditField = uieditfield(app.Tab, 'numeric');
            app.CicloEditFieldLabel = uilabel(app.Tab);
            app.CicloEditFieldLabel.Text = 'Ciclo';
            app.CicloEditField = uieditfield(app.Tab, 'numeric');
            app.CicloEditField.ValueChangedFcn = createCallbackFcn(app, @SealDropDownValueChanged, true);
            app.AnchoEditFieldLabel = uilabel(app.Tab);
            app.AnchoEditFieldLabel.Text = 'Ancho';
            app.AnchoEditField = uieditfield(app.Tab, 'numeric');
            app.AnchoEditField.ValueChangedFcn = createCallbackFcn(app, @SealDropDownValueChanged, true);
            app.DesfaseEditFieldLabel = uilabel(app.Tab);
            app.DesfaseEditFieldLabel.Text = 'Desfase';
            app.DesfaseEditField = uieditfield(app.Tab, 'numeric');
            app.DesfaseEditField.ValueChangedFcn = createCallbackFcn(app, @SealDropDownValueChanged, true);
            app.BaseEditFieldLabel = uilabel(app.Tab);
            app.BaseEditFieldLabel.Text = 'Base';
            app.BaseEditField = uieditfield(app.Tab, 'numeric');
            app.Switch = uiswitch(app.Tab, 'slider');
            app.Switch.Items = {'+', '-'};
            app.Switch.ItemsData = [0 1];
            app.Switch.Value = 0;
            app.Knob = uiknob(app.Tab, 'discrete');
            app.Knob.Items = {'real x(t)', 'Im x(t)', 'Magnitud x(t)', 'Arg x(t)'};
            app.Knob.ItemsData = [0 1 2 3];
            app.Knob.Value = 0;
            app.SealDropDownLabel = uilabel(app.Tab);
            app.SealDropDownLabel.Text = 'Señal';
            app.SealDropDown = uidropdown(app.Tab);
            app.SealDropDown.Items = {'Selecciona', 'Cuadrada', 'Diente de sierra', 'Senoidal', 'Exponencial', 'E Compleja', 'Escalón', 'Impulso', 'Rampa', 'Rectangular', 'Triangular'};
            app.SealDropDown.ItemsData = [0 1 2 3 4 5 6 7 8 9 10 11];
            app.SealDropDown.ValueChangedFcn = createCallbackFcn(app, @SealDropDownValueChanged2, true);
            app.SealDropDown.Value = 0;
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = practica3_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end