classdef Practica_4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        Tab                         matlab.ui.container.Tab
        Ciclo2EditField             matlab.ui.control.NumericEditField
        Ciclo2EditFieldLabel        matlab.ui.control.Label
        Ciclo1EditField             matlab.ui.control.NumericEditField
        Ciclo1EditFieldLabel        matlab.ui.control.Label
        Desfase2EditField           matlab.ui.control.NumericEditField
        Desfase2EditFieldLabel      matlab.ui.control.Label
        Ancho2EditField             matlab.ui.control.NumericEditField
        Ancho2EditFieldLabel        matlab.ui.control.Label
        Desfase1EditField           matlab.ui.control.NumericEditField
        Desfase1EditFieldLabel      matlab.ui.control.Label
        Ancho1EditField             matlab.ui.control.NumericEditField
        Ancho1EditFieldLabel        matlab.ui.control.Label
        Amplitud1EditField          matlab.ui.control.NumericEditField
        Amplitud1EditFieldLabel     matlab.ui.control.Label
        Amplitud2EditField          matlab.ui.control.NumericEditField
        Amplitud2EditFieldLabel     matlab.ui.control.Label
        Compleja2SwitchLabel        matlab.ui.control.Label
        Compleja2Switch             matlab.ui.control.Switch
        Compleja1SwitchLabel        matlab.ui.control.Label
        Compleja1Switch             matlab.ui.control.Switch
        TiempoFinEditField          matlab.ui.control.NumericEditField
        TiempoFinEditFieldLabel     matlab.ui.control.Label
        TiempoIniEditField          matlab.ui.control.NumericEditField
        TiempoIniEditFieldLabel     matlab.ui.control.Label
        Base2EditField              matlab.ui.control.NumericEditField
        Base2EditFieldLabel         matlab.ui.control.Label
        Base1EditField              matlab.ui.control.NumericEditField
        Base1EditFieldLabel         matlab.ui.control.Label
        PeriodosEditField           matlab.ui.control.NumericEditField
        PeriodosEditFieldLabel      matlab.ui.control.Label
        F0EditField                 matlab.ui.control.NumericEditField
        F0EditFieldLabel            matlab.ui.control.Label
        FsEditField                 matlab.ui.control.NumericEditField
        FsEditFieldLabel            matlab.ui.control.Label
        Grafica2DropDown            matlab.ui.control.DropDown
        Grafica2DropDownLabel       matlab.ui.control.Label
        Exponente2Switch            matlab.ui.control.Switch
        Exponente2SwitchLabel       matlab.ui.control.Label
        ExponenteSwitch             matlab.ui.control.Switch
        ExponenteSwitchLabel        matlab.ui.control.Label
        Grafica1DropDown            matlab.ui.control.DropDown
        Grafica1DropDownLabel       matlab.ui.control.Label
        OperacionDropDown           matlab.ui.control.DropDown
        OperacionDropDownLabel      matlab.ui.control.Label
        EscalamientoEditField       matlab.ui.control.NumericEditField
        EscalamientoEditFieldLabel  matlab.ui.control.Label
        GraficarButton              matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
        Tab2                        matlab.ui.container.Tab
        UIAxes5                     matlab.ui.control.UIAxes
        UIAxes4                     matlab.ui.control.UIAxes
        UIAxes3                     matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        Button                      matlab.ui.control.Button
    end

    
    properties (Access = private)
        Funcion; % guardar funcion generada
        intervalo;

    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.EscalamientoEditField.Visible="off";
            app.EscalamientoEditFieldLabel.Visible="off";

            app.Grafica2DropDown.Visible="off";
            app.Grafica2DropDownLabel.Visible="off";

            app.ExponenteSwitch.Visible="off";
            app.ExponenteSwitchLabel.Visible="off";

            app.Exponente2Switch.Visible="off";
            app.Exponente2SwitchLabel.Visible="off";

            app.Compleja1Switch.Visible="off";
            app.Compleja1SwitchLabel.Visible="off";

            app.Compleja2Switch.Visible="off";
            app.Compleja2SwitchLabel.Visible="off";

            app.Amplitud1EditField.Visible="off";
            app.Amplitud1EditFieldLabel.Visible="off";
            
            app.Amplitud2EditField.Visible="off";
            app.Amplitud2EditFieldLabel.Visible="off";

            app.Ancho1EditField.Visible="off";
            app.Ancho1EditFieldLabel.Visible="off";

            app.Ancho2EditField.Visible="off";
            app.Ancho2EditFieldLabel.Visible="off";

            app.Desfase1EditField.Visible="off";
            app.Desfase1EditFieldLabel.Visible="off";

            app.Desfase2EditField.Visible="off";
            app.Desfase2EditFieldLabel.Visible="off";

            app.Base1EditField.Visible="off";
            app.Base1EditFieldLabel.Visible="off";

            app.Base2EditField.Visible="off";
            app.Base2EditFieldLabel.Visible="off";

            app.Ciclo1EditField.Visible="off";
            app.Ciclo1EditFieldLabel.Visible="off";

            app.Ciclo2EditField.Visible="off";
            app.Ciclo2EditFieldLabel.Visible="off";
            
            app.Ancho1EditField.Visible="off";
            app.Ancho1EditFieldLabel.Visible="off";

            app.Desfase1EditField.Visible="off";
            app.Desfase1EditFieldLabel.Visible="off";

            app.Base1EditField.Visible="off";
            app.Base1EditFieldLabel.Visible="off";

            app.Ciclo1EditField.Visible="off";
            app.Ciclo1EditFieldLabel.Visible="off";

            app.Ciclo2EditField.Visible="off";
            app.Ciclo2EditFieldLabel.Visible="off";

            app.Amplitud2EditField.Visible="off";
            app.Amplitud2EditFieldLabel.Visible="off";

            app.Ancho2EditField.Visible="off";
            app.Ancho2EditFieldLabel.Visible="off";

            app.Desfase2EditField.Visible="off";
            app.Desfase2EditFieldLabel.Visible="off";

            app.Base2EditField.Visible="off";
            app.Base2EditFieldLabel.Visible="off";


        end

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
            operacion=app.OperacionDropDown.Value;
            graph=app.Grafica1DropDown.Value;%Opcion de grafica
            A=app.Amplitud1EditField.Value;%%Amplitud inicial
            fs=app.FsEditField.Value;%%Muestras por segundo
            f=app.F0EditField.Value; %%frecuencia fundamental
            p=app.PeriodosEditField.Value;%%Periodos
            t1=app.TiempoIniEditField.Value;%%Tiempo inicial
            duty=app.Ciclo1EditField.Value;%%Ciclo de trabajo
            width=app.Ancho1EditField.Value;%%Ancho si aplica
            des=app.Desfase1EditField.Value;
            B=app.Base1EditField.Value;%%Base de la funcion exponencial
            T=p/f;
            if operacion==1 %%Escalamiento
                t=t1:1/fs:T-1/fs;
                esc=app.EscalamientoEditField.Value;
                if graph==1%%onda cuadrada
                    signal=esc.*A.*square(2*pi*f*t+des,duty);%% 2 por pi por la freq por el t mas un desfase
                     text_title='Señal de onda cuadrada con amplitud variable';

                end
                if graph==2%%diente de sierra
                    signal=esc.*A.*sawtooth(2*pi*t+des,width);
                    text_title='Señal diente de sierra con amplitud variable';
                end
                
                if graph==3%%senoidal
                    signal=esc.*A.*sin(2*pi*f*t+des);
                    text_title='Señal senoidal con amplitud variable';
                end
                if graph==4%%Señal exponencial
                
                    if app.ExponenteSwitch.Value==0 %%Switch del exponente
                        signal=esc.*A.*B.^(-t);
                    end
                    if app.ExponenteSwitch.Value==1
                        signal=esc.*A.*B.^(t);
                    end
                    text_title='Señal Exponencial';
                end

                if graph==5%%Exponencial compleja
              
                    x=esc.*A.*exp(1i.*(f.*t+pi/4));
                    if app.Compleja1Switch.Value=='0' %0 de imaginaria
                        signal=imag(x);
                    end
                    if app.Compleja1Switch.Value=='1' %1 de real
                        signal=real(x);
                    end
  
                    text_title='Señal exponencial compleja';

                end

                if graph==6%% Señal escalon
                    signal=A.*esc.*ones(2,length(t));
                    signal(1)=0;
                    text_title='Señal Escalon unitario';
                end

                if graph==7%%Impulso
                    t = -2:1/1000:2;
                    % signal=zeros(length(t));
                    % signal(1000)=esc;

                    signal = zeros(1, length(t));
                    signal(2000)=A*esc; % Impulso en 0

                    % for i = 1: length(t) % Existe un impulso de valor "esc" por cada periodo "t"
                    %     if mod(t(i), f) == 0
                    %         signal(i) = esc;
                    %     end
                    % end

                    text_title='Señal Impulso Unitario';
                end

                if graph==8%%Rampa
                    signal=A.*esc.*(t);
                    text_title='Señal Rampa';
                end

                if graph==9%% Rectangular
                    t = -2.5:1/1000:2.5;
                    signal=A.*esc.*rectpuls(t,1);
                    text_title='Señal rectangular';
                end

                if graph==10%%Triangular
                    t = -2.5:1/1000:2.5;
                    signal=A.*esc.*tripuls(t,1);
                    text_title='Señal Triangular';
                end         
            end%%Escalamiento

%% Suma de funciones ========================================================================
            if operacion==2
                t=0:1/1000:5;
                graph2=app.Grafica2DropDown.Value;
                A2=app.Amplitud2EditField.Value;%%Amplitud de la grafica 2
                duty2=app.Ciclo2EditField.Value;
                width2=app.Ancho2EditField.Value;
                des2=app.Desfase2EditField.Value;
                B2=app.Base2EditField.Value;

                %%Primero determinamos la grafica 1
                if graph==1 % Señal cuadrada
                    sign1=A.*square(2*pi*f*t+des,duty);
                end

                if graph==2 % Señal diente de sierra
                    sign1=A.*sawtooth(2*pi*t+des,width);
                end

                if graph==3 % Señal Senoidal
                    sign1=A.*sin(2*pi*f*t+des);
                end

                if graph==4 % Señal Exponencial
                    if app.ExponenteSwitch.Value==0 %%Switch del exponente
                        sign1=A.*B.^(-t);
                    end
                    if app.ExponenteSwitch.Value==1
                        sign1=A.*B.^(t);
                    end
                end

                if graph==5 % Señal E. compleja
                    x=A.*exp(1i.*(2.*t+pi/4));
                    if app.Compleja1Switch.Value=='0'%compleja
                        sign1=imag(x);
                    end
                    if app.Compleja1Switch.Value=='1'
                        sign1=real(x);
                    end

                end

                if graph==6 % Señal escalon
                    sign1=A.*ones(2,length(t));
                    sign1(1)=0;
                end

                if graph==7 %Impulso
                    t = -2:1/1000:2;

                    sign1 = zeros(1, length(t));
                    sign1(2000)=1*A; % Impulso en 0
                end

                if graph==8 % Señal Rampa
                    sign1=A*(t);
                end

                if graph==9 % Señal Rectangular
                    t = -2.5:1/1000:2.5;
                    sign1=A.*rectpuls(t,1);
                end

                if graph==10 % Señal Triangular
                    t = -2.5:1/1000:2.5;
                    sign1=A.*tripuls(t,1);
                end  

                %%Determinamos la grafica 2
                if graph2==1
                    sign2=A2.*square(2*pi*f*t+des2,duty2);
                end

                if graph2==2
                    sign2=A2.*sawtooth(2*pi*t+des2,width2);
                end

                if graph2==3
                    sign2=A2.*sin(2*pi*f*t+des2);
                end
                
                if graph2==4
                    if app.Exponente2Switch.Value=='0'%negativa
                        sign2=A2.*B2.^(-t);
                    end
                    if app.Exponente2Switch.Value=='1'%positiva
                        sign2=A2.*B2.^(t);
                    end
                end

                if graph2==5%%Exponencial compleja
                    x=A2.*exp(1i.*(2.*t+pi/4));%%Generamos funcion compleja
                    if app.Compleja1Switch.Value=='0'%compleja
                        sign2=imag(x);
                    end
                    if app.Compleja1Switch.Value=='1'
                        sign2=real(x);
                    end
                    
                end

                if graph2==6 % Señal escalon
                    sign2=A2.*ones(2,length(t));
                    sign2(1)=0;
                end

                if graph2==7 %Impulso
                    t = -2:1/1000:2;

                    sign2 = zeros(1, length(t));
                    sign2(2000)=1*A2; % Impulso en 0
                end

                if graph2==8 % Señal Rampa
                    sign2=A2*(t);
                end

                if graph2==9 % Señal Rectangular
                    t = -2.5:1/1000:2.5;
                    sign2=A2.*rectpuls(t,1);
                end

                if graph2==10 % Señal Triangular
                    t = -2.5:1/1000:2.5;
                    sign2=A2.*tripuls(t,1);
                end  

                % Suma de funciones
                signal = size(1, length(t));

                for i = 1: length(t)
                    signal(i) = sign1(i) + sign2(i);
                end

                % signal=sign1+sign2;
                text_title='Suma de señales';


            end%%End suma

%% Producto de funciones ====================================================================================
            if operacion==3
                t=0:1/1000:5;
                graph2=app.Grafica2DropDown.Value;
                A2=app.Amplitud2EditField.Value;%%Amplitud de la grafica 2
                duty2=app.Ciclo2EditField.Value;
                width2=app.Ancho2EditField.Value;
                des2=app.Desfase2EditField.Value;
                B2=app.Base2EditField.Value;

                %%Primero determinamos la grafica 1
                if graph==1 % Señal cuadrada
                    sign1=A.*square(2*pi*f*t+des,duty);
                end

                if graph==2 % Señal diente de sierra
                    sign1=A.*sawtooth(2*pi*t+des,width);
                end

                if graph==3 % Señal Senoidal
                    sign1=A.*sin(2*pi*f*t+des);
                end

                if graph==4 % Señal Exponencial
                    if app.ExponenteSwitch.Value==0 %%Switch del exponente
                        sign1=A.*B.^(-t);
                    end
                    if app.ExponenteSwitch.Value==1
                        sign1=A.*B.^(t);
                    end
                end

                if graph==5 % Señal E. compleja
                    x=A.*exp(1i.*(2.*t+pi/4));
                    if app.Compleja1Switch.Value=='0'%compleja
                        sign1=imag(x);
                    end
                    if app.Compleja1Switch.Value=='1'
                        sign1=real(x);
                    end

                end

                if graph==6 % Señal escalon
                    sign1=A.*ones(2,length(t));
                    sign1(1)=0;
                end

                if graph==7 %Impulso
                    t = -2:1/1000:2;

                    sign1 = zeros(1, length(t));
                    sign1(2000)=1*A; % Impulso en 0
                end

                if graph==8 % Señal Rampa
                    sign1=A*(t);
                end

                if graph==9 % Señal Rectangular
                    t = -2.5:1/1000:2.5;
                    sign1=A.*rectpuls(t,1);
                end

                if graph==10 % Señal Triangular
                    t = -2.5:1/1000:2.5;
                    sign1=A.*tripuls(t,1);
                end  


                %%Determinamos la grafica 2
                if graph2==1
                    sign2=A2.*square(2*pi*f*t+des2,duty2);
                end

                if graph2==2
                    sign2=A2.*sawtooth(2*pi*t+des2,width2);
                end

                if graph2==3
                    sign2=A2.*sin(2*pi*f*t+des2);
                end
                
                if graph2==4
                    if app.Exponente2Switch.Value=='0'%negativa
                        sign2=A2.*B2.^(-t);
                    end
                    if app.Exponente2Switch.Value=='1'%positiva
                        sign2=A2.*B2.^(t);
                    end
                end

                if graph2==5%%Exponencial compleja
                    x=A2.*exp(1i.*(2.*t+pi/4));%%Generamos funcion compleja
                    if app.Compleja1Switch.Value=='0'%compleja
                        sign2=imag(x);
                    end
                    if app.Compleja1Switch.Value=='1'
                        sign2=real(x);
                    end
                    
                end

                if graph==6 % Señal escalon
                    signal=A2.*ones(2,length(t));
                    sign2(1)=0;
                end

                if graph==7 %Impulso
                    t = -2:1/1000:2;

                    sign2 = zeros(1, length(t));
                    sign2(2000)=1*A2; % Impulso en 0
                end

                if graph==8 % Señal Rampa
                    sign2=A2*(t);
                end

                if graph==9 % Señal Rectangular
                    t = -2.5:1/1000:2.5;
                    sign2=A2.*rectpuls(t,1);
                end

                if graph==10 % Señal Triangular
                    t = -2.5:1/1000:2.5;
                    sign2=A2.*tripuls(t,1);
                end  

                % multiplicacion de funciones
                signal = size(1, length(t));

                % for i = 1: length(t)
                %     signal(i) = sign1(i) * sign2(i);
                % end
                
                signal=sign1.*sign2;
                text_title='Multiplicacion de señales';

            end%%End multiplicacion de funciones

%% Diferenciación ====================================================================================
            if operacion==4%%Diferenciación 
                t=0:1/1000:5;

                if graph==1 % Señal cuadrada
                    sign1=A.*square(2*pi*f*t+des,duty);
                end

                if graph==2 % Señal diente de sierra
                    sign1=A.*sawtooth(2*pi*t+des,width);
                end

                if graph==3 % Señal Senoidal
                    sign1=A.*sin(2*pi*f*t+des);
                end

                if graph==4 % Señal Exponencial
                    if app.ExponenteSwitch.Value==0 %%Switch del exponente
                        sign1=A.*B.^(-t);
                    end
                    if app.ExponenteSwitch.Value==1
                        sign1=A.*B.^(t);
                    end
                end

                if graph==5 % Señal E. compleja
                    x=A.*exp(1i.*(2.*t+pi/4));
                    if app.Compleja1Switch.Value=='0'%compleja
                        sign1=imag(x);
                    end
                    if app.Compleja1Switch.Value=='1'
                        sign1=real(x);
                    end

                end

                if graph==6 % Señal escalon
                    sign1=A.*ones(2,length(t));
                    sign1(1)=0;
                end

                if graph==7 %Impulso
                    t = -2:1/1000:2;

                    sign1 = zeros(1, length(t));
                    sign1(2000)=1*A; % Impulso en 0
                end

                if graph==8 % Señal Rampa
                    sign1=A*(t);
                end

                if graph==9 % Señal Rectangular
                    t = -2.5:1/1000:2.5;
                    sign1=A.*rectpuls(t,1);
                end

                if graph==10 % Señal Triangular
                    t = -2.5:1/1000:2.5;
                    sign1=A.*tripuls(t,1);
                end  


                % Derivada de la funcion
                signal = diff(sign1) * fs;
                signal(length(t)) = 0;

                text_title='Diferenciacion de señales';

            end%%end Diferenciacion

%% Integracion ====================================================================================
            if operacion==5 % Integracion 
                syms X; % Variable de tipo simbolico
                t=0:1/1000:5;

                if graph==1 % Señal cuadrada
                    y0 = 2 * pi * f * X + des;
                    y1 = int(y0, X);
                    yd = 2 * pi * f;

                    y2 = double(subs(y1, X, t));

                    sign1=A.*(square(y2,duty))/yd;
                end

                if graph==2 % Señal diente de sierra
                    y0 = 2 * pi * f * X + des;
                    y1 = int(y0, X);
                    yd = 2 * pi * f;

                    y2 = double(subs(y1, X, t));

                    sign1=A.*(sawtooth(y2,width))/yd;

                end

                if graph==3 % Señal Senoidal
                    yd = 2 * pi * f;

                    sign1=-A.*(cos(2*pi*f*t+des))/yd;
                end

                if graph==4 % Señal Exponencial
                    if app.ExponenteSwitch.Value==0 %%Switch del exponente
                        sign1=-A.*(B.^(-t))/log(B);
                    end
                    
                    if app.ExponenteSwitch.Value==1
                        sign1=A.*(B.^(t))/log(B);
                    end
                end

                if graph==5 % Señal E. compleja
                    x=A.*exp(1i.*(2.*t+pi/4));
                    if app.Compleja1Switch.Value=='0'%compleja
                        sign1=imag(x);
                    end
                    if app.Compleja1Switch.Value=='1'
                        sign1=real(x);
                    end

                end

                if graph==6 % Señal escalon
                    sign1=A.*ones(2,length(t));
                    sign1(1)=0;
                end

                if graph==7 %Impulso
                    t = -2:1/1000:2;

                    sign1 = zeros(1, length(t));
                    sign1(2000)=1*A; % Impulso en 0
                end

                if graph==8 % Señal Rampa
                    sign1=A*(t);
                end

                if graph==9 % Señal Rectangular
                    t = -2.5:1/1000:2.5;
                    sign1=A.*rectpuls(t,1);
                end

                if graph==10 % Señal Triangular
                    t = -2.5:1/1000:2.5;
                    sign1=A.*tripuls(t,1);
                end  


                % Derivada de la funcion
                % sign1 = double(subs(sign1,t)); % No sé para que sirva pero sin esto no funciona
                
                % xt = subs(sign1, t, tiemposimbolo);
                % 
                % % xt = sign1(tiemposimbolo); %Generación de Señal x(t)
                % yt = int(xt,tiemposimbolo); %Cálculo de la integral y(t)=integral de x(t) 
                % signal = double(subs(yt,tiemposimbolo,t)); %Cambio de variable 
                
                signal = sign1;

                
                text_title='Diferenciacion de señales';

            end%%end Integración

%% ====================================================================================
            if operacion==6%%Escalamiento de tiempo
            end
%% ====================================================================================
            if operacion==7%%Reflexion
            end
%% ====================================================================================
            if operacion == 8%% corrimiento
            end
%% ====================================================================================
            
            app.Funcion=signal;
            app.intervalo=t;
            xlabel(app.UIAxes,"Tiempo (sec)")
            ylabel(app.UIAxes,"Amplitud")
            title(app.UIAxes,text_title)
            plot(app.UIAxes,t,signal)
            grid(app.UIAxes,"on")


            % Graficando la señal alfa en la tab2
            plot(app.UIAxes2, t, signal);   
            title(app.UIAxes2, text_title);

            % Generando las señales par e impar de la señal alfa
            F = fliplr(signal);
            F_par = (signal + F) / 2;
            F_impar = (signal - F) / 2;

            plot(app.UIAxes3, t, F_par);
            plot(app.UIAxes4, t, F_impar);
            plot(app.UIAxes5, t, F_impar + F_par);


        end

        % Value changed function: OperacionDropDown
        function OperacionDropDownValueChanged(app, event)
            value = app.OperacionDropDown.Value;
             %%Cambiar que opciones son visibles dentro de las operaciones
            
            if value==1 %%Para agrandar la señal solo necesitamos el escalamiento, amplitud, periodo y desfase
                app.EscalamientoEditField.Visible="on";
                app.EscalamientoEditFieldLabel.Visible="on";

                app.Grafica2DropDown.Visible="off";
                app.Grafica2DropDownLabel.Visible="off";

                app.ExponenteSwitch.Visible="off";
                app.ExponenteSwitchLabel.Visible="off";
    
                app.Exponente2Switch.Visible="off";
                app.Exponente2SwitchLabel.Visible="off";
    
                app.Compleja1Switch.Visible="off";
                app.Compleja1SwitchLabel.Visible="off";
    
                app.Compleja2Switch.Visible="off";
                app.Compleja2SwitchLabel.Visible="off";
                
                app.Amplitud1EditField.Visible="on";
                app.Amplitud1EditFieldLabel.Visible="on";

                app.PeriodosEditField.Visible="on";
                app.PeriodosEditFieldLabel.Visible="on";

                app.Desfase1EditField.Visible="on";
                app.Desfase1EditFieldLabel.Visible="on";

                app.Ciclo1EditField.Visible="off";%%Duty cycle
                app.Ciclo1EditFieldLabel.Visible="off";

                app.Amplitud2EditField.Visible="off";
                app.Amplitud2EditFieldLabel.Visible="off";

                app.Desfase2EditField.Visible="off";
                app.Desfase2EditFieldLabel.Visible="off";

                app.Ciclo2EditField.Visible="off";
                app.Ciclo2EditFieldLabel.Visible="off";

            end
            if value==2%%Suma de funciones aqui necesitamos los parametros de la funcion 2
                app.EscalamientoEditField.Visible="off";
                app.EscalamientoEditFieldLabel.Visible="off";

                app.Grafica2DropDown.Visible="on";
                app.Grafica2DropDownLabel.Visible="on";

                app.ExponenteSwitch.Visible="off";
                app.ExponenteSwitchLabel.Visible="off";
    
                app.Exponente2Switch.Visible="off";
                app.Exponente2SwitchLabel.Visible="off";
    
                app.Compleja1Switch.Visible="off";
                app.Compleja1SwitchLabel.Visible="off";
    
                app.Compleja2Switch.Visible="off";
                app.Compleja2SwitchLabel.Visible="off";

                app.Amplitud1EditField.Visible="off";
                app.Amplitud1EditFieldLabel.Visible="off";

                app.Desfase1EditField.Visible="on";
                app.Desfase1EditFieldLabel.Visible="on";

                app.Ciclo1EditField.Visible="off";%%Duty cycle
                app.Ciclo1EditFieldLabel.Visible="off";

                app.Amplitud2EditField.Visible="off";
                app.Amplitud1EditFieldLabel.Visible="off";

                app.Desfase2EditField.Visible="on";
                app.Desfase2EditFieldLabel.Visible="on";

                app.Ciclo2EditField.Visible="off";
                app.Ciclo2EditFieldLabel.Visible="off";

            end
            if value==3%%Multiplicacion de funciones
                app.EscalamientoEditField.Visible="off";
                app.EscalamientoEditFieldLabel.Visible="off";

                app.Grafica2DropDown.Visible="on";
                app.Grafica2DropDownLabel.Visible="on";

                app.ExponenteSwitch.Visible="off";
                app.ExponenteSwitchLabel.Visible="off";
    
                app.Exponente2Switch.Visible="off";
                app.Exponente2SwitchLabel.Visible="off";
    
                app.Compleja1Switch.Visible="off";
                app.Compleja1SwitchLabel.Visible="off";
    
                app.Compleja2Switch.Visible="off";
                app.Compleja2SwitchLabel.Visible="off";

                app.Amplitud1EditField.Visible="on";
                app.Amplitud1EditFieldLabel.Visible="on";

                app.Desfase1EditField.Visible="on";
                app.Desfase1EditFieldLabel.Visible="on";

                app.Ciclo1EditField.Visible="off";%%Duty cycle
                app.Ciclo1EditFieldLabel.Visible="off";

                app.Amplitud2EditField.Visible="off";
                app.Amplitud1EditFieldLabel.Visible="off";

                app.Desfase2EditField.Visible="off";
                app.Desfase2EditFieldLabel.Visible="off";

                app.Ciclo2EditField.Visible="off";
                app.Ciclo2EditFieldLabel.Visible="off";

            end
            if value==4%%Diferenciacion
                app.EscalamientoEditField.Visible="off";
                app.EscalamientoEditFieldLabel.Visible="on";

                app.Grafica2DropDown.Visible="off";
                app.Grafica2DropDownLabel.Visible="off";

                app.ExponenteSwitch.Visible="off";
                app.ExponenteSwitchLabel.Visible="off";
    
                app.Exponente2Switch.Visible="off";
                app.Exponente2SwitchLabel.Visible="off";
    
                app.Compleja1Switch.Visible="off";
                app.Compleja1SwitchLabel.Visible="off";
    
                app.Compleja2Switch.Visible="off";
                app.Compleja2SwitchLabel.Visible="off";

                app.Amplitud1EditField.Visible="on";
                app.Amplitud1EditFieldLabel.Visible="on";

                app.Desfase1EditField.Visible="on";
                app.Desfase1EditFieldLabel.Visible="on";

                app.Ciclo1EditField.Visible="off";%%Duty cycle
                app.Ciclo1EditFieldLabel.Visible="off";

                app.Amplitud2EditField.Visible="off";
                app.Amplitud1EditFieldLabel.Visible="off";

                app.Desfase2EditField.Visible="off";
                app.Desfase2EditFieldLabel.Visible="off";

                app.Ciclo2EditField.Visible="off";
                app.Ciclo2EditFieldLabel.Visible="off";
            end
            if value==5%%Integracion
                app.EscalamientoEditField.Visible="off";
                app.EscalamientoEditFieldLabel.Visible="on";

                app.Grafica2DropDown.Visible="off";
                app.Grafica2DropDownLabel.Visible="off";

                app.ExponenteSwitch.Visible="off";
                app.ExponenteSwitchLabel.Visible="off";
    
                app.Exponente2Switch.Visible="off";
                app.Exponente2SwitchLabel.Visible="off";
    
                app.Compleja1Switch.Visible="off";
                app.Compleja1SwitchLabel.Visible="off";
    
                app.Compleja2Switch.Visible="off";
                app.Compleja2SwitchLabel.Visible="off";

                app.Amplitud1EditField.Visible="on";
                app.Amplitud1EditFieldLabel.Visible="on";

                app.Desfase1EditField.Visible="on";
                app.Desfase1EditFieldLabel.Visible="on";

                app.Ciclo1EditField.Visible="off";%%Duty cycle
                app.Ciclo1EditFieldLabel.Visible="off";

                app.Amplitud2EditField.Visible="off";
                app.Amplitud1EditFieldLabel.Visible="off";

                app.Desfase2EditField.Visible="off";
                app.Desfase2EditFieldLabel.Visible="off";

                app.Ciclo2EditField.Visible="off";
                app.Ciclo2EditFieldLabel.Visible="off";
            end
        end

        % Value changed function: Grafica1DropDown
        function Grafica1DropDownValueChanged(app, event)
            %%Cambios de valor en el dropdown de la primera grafica
            value = app.Grafica1DropDown.Value;
            if value==1 %%Si es grafica Cuadrada
                app.ExponenteSwitch.Visible="off";
                app.ExponenteSwitchLabel.Visible="off";

                app.Compleja1Switch.Visible="off";
                app.Compleja1SwitchLabel.Visible="off";

                app.Amplitud1EditField.Visible="on";
                app.Amplitud1EditFieldLabel.Visible="on";

                app.Ciclo1EditField.Visible="on";
                app.Ciclo1EditFieldLabel.Visible="on";

                app.Ancho1EditField.Visible="off";
                app.Ancho1EditFieldLabel.Visible="off";

                app.Base1EditField.Visible="off";
                app.Base1EditFieldLabel.Visible="off";

                app.Desfase1EditField.Visible="on";
                app.Desfase1EditField.Visible="on";


            end

            if value==2%%Si es grafica diente de sierra
                app.ExponenteSwitch.Visible="off";
                app.ExponenteSwitchLabel.Visible="off";

                app.Compleja1Switch.Visible="off";
                app.Compleja1SwitchLabel.Visible="off";

                app.Amplitud1EditField.Visible="on";
                app.Amplitud1EditFieldLabel.Visible="on";

                app.Ciclo1EditField.Visible="off";
                app.Ciclo1EditFieldLabel.Visible="off";

                app.Ancho1EditField.Visible="on";
                app.Ancho1EditFieldLabel.Visible="on";

                app.Base1EditField.Visible="off";
                app.Base1EditFieldLabel.Visible="off";

                app.Desfase1EditField.Visible="on";
                app.Desfase1EditField.Visible="on";
            end

            if value==3%%Si es grafica Senoidal
                app.ExponenteSwitch.Visible="off";
                app.ExponenteSwitchLabel.Visible="off";

                app.Compleja1Switch.Visible="off";
                app.Compleja1SwitchLabel.Visible="off";

                app.Amplitud1EditField.Visible="on";
                app.Amplitud1EditFieldLabel.Visible="on";

                app.Ciclo1EditField.Visible="off";
                app.Ciclo1EditFieldLabel.Visible="off";

                app.Ancho1EditField.Visible="off";
                app.Ancho1EditFieldLabel.Visible="off";

                app.Base1EditField.Visible="off";
                app.Base1EditFieldLabel.Visible="off";

                app.Desfase1EditField.Visible="on";
                app.Desfase1EditField.Visible="on";
            end

            if value==4%%Si es grafica exponencial
                app.ExponenteSwitch.Visible="on";
                app.ExponenteSwitchLabel.Visible="on";

                app.Compleja1Switch.Visible="off";
                app.Compleja1SwitchLabel.Visible="off";

                app.Amplitud1EditField.Visible="on";
                app.Amplitud1EditFieldLabel.Visible="on";

                app.Ciclo1EditField.Visible="off";
                app.Ciclo1EditFieldLabel.Visible="off";

                app.Ancho1EditField.Visible="off";
                app.Ancho1EditFieldLabel.Visible="off";

                app.Base1EditField.Visible="on";
                app.Base1EditFieldLabel.Visible="on";

                app.Desfase1EditField.Visible="off";
                app.Desfase1EditField.Visible="off";
            end

            if value==5%%Si es grafica exponencial compleja
                app.ExponenteSwitch.Visible="off";
                app.ExponenteSwitchLabel.Visible="off";

                app.Compleja1Switch.Visible="on";
                app.Compleja1SwitchLabel.Visible="on";

                app.Amplitud1EditField.Visible="on";
                app.Amplitud1EditFieldLabel.Visible="on";

                app.Ciclo1EditField.Visible="off";
                app.Ciclo1EditFieldLabel.Visible="off";

                app.Ancho1EditField.Visible="off";
                app.Ancho1EditFieldLabel.Visible="off";

                app.Base1EditField.Visible="off";
                app.Base1EditFieldLabel.Visible="off";

                app.Desfase1EditField.Visible="off";
                app.Desfase1EditFieldLabel.Visible="off";

            end
            if value==6%%Escalon
                app.ExponenteSwitch.Visible="off";
                app.ExponenteSwitchLabel.Visible="off";

                app.Compleja1Switch.Visible="off";
                app.Compleja1SwitchLabel.Visible="off";

                app.Amplitud1EditField.Visible="on";
                app.Amplitud1EditFieldLabel.Visible="on";

                app.Ciclo1EditField.Visible="off";
                app.Ciclo1EditFieldLabel.Visible="off";

                app.Ancho1EditField.Visible="off";
                app.Ancho1EditFieldLabel.Visible="off";

                app.Base1EditField.Visible="off";
                app.Base1EditFieldLabel.Visible="off";

                app.Desfase1EditField.Visible="on";
                app.Desfase1EditField.Visible="on";
            end
            if value==7%%Impulso
                app.ExponenteSwitch.Visible="off";
                app.ExponenteSwitchLabel.Visible="off";

                app.Compleja1Switch.Visible="off";
                app.Compleja1SwitchLabel.Visible="off";

                app.Amplitud1EditField.Visible="on";
                app.Amplitud1EditFieldLabel.Visible="on";

                app.Ciclo1EditField.Visible="off";
                app.Ciclo1EditFieldLabel.Visible="off";

                app.Ancho1EditField.Visible="off";
                app.Ancho1EditFieldLabel.Visible="off";

                app.Base1EditField.Visible="off";
                app.Base1EditFieldLabel.Visible="off";

                app.Desfase1EditField.Visible="on";
                app.Desfase1EditField.Visible="on";
            end
            if value==8%%Rampa
                app.ExponenteSwitch.Visible="off";
                app.ExponenteSwitchLabel.Visible="off";

                app.Compleja1Switch.Visible="off";
                app.Compleja1SwitchLabel.Visible="off";

                app.Amplitud1EditField.Visible="on";
                app.Amplitud1EditFieldLabel.Visible="on";

                app.Ciclo1EditField.Visible="off";
                app.Ciclo1EditFieldLabel.Visible="off";

                app.Ancho1EditField.Visible="off";
                app.Ancho1EditFieldLabel.Visible="off";

                app.Base1EditField.Visible="off";
                app.Base1EditFieldLabel.Visible="off";

                app.Desfase1EditField.Visible="on";
                app.Desfase1EditField.Visible="on";
            end
            if value==9%%Rectangular
                app.ExponenteSwitch.Visible="off";
                app.ExponenteSwitchLabel.Visible="off";

                app.Compleja1Switch.Visible="off";
                app.Compleja1SwitchLabel.Visible="off";

                app.Amplitud1EditField.Visible="on";
                app.Amplitud1EditFieldLabel.Visible="on";

                app.Ciclo1EditField.Visible="off";
                app.Ciclo1EditFieldLabel.Visible="off";

                app.Ancho1EditField.Visible="off";
                app.Ancho1EditFieldLabel.Visible="off";

                app.Base1EditField.Visible="off";
                app.Base1EditFieldLabel.Visible="off";

                app.Desfase1EditField.Visible="off";
                app.Desfase1EditField.Visible="off";
            end
            if value==10%%Triangular
                app.ExponenteSwitch.Visible="off";
                app.ExponenteSwitchLabel.Visible="off";

                app.Compleja1Switch.Visible="off";
                app.Compleja1SwitchLabel.Visible="off";

                app.Amplitud1EditField.Visible="on";
                app.Amplitud1EditFieldLabel.Visible="on";

                app.Ciclo1EditField.Visible="off";
                app.Ciclo1EditFieldLabel.Visible="off";

                app.Ancho1EditField.Visible="off";
                app.Ancho1EditFieldLabel.Visible="off";

                app.Base1EditField.Visible="off";
                app.Base1EditFieldLabel.Visible="off";

                app.Desfase1EditField.Visible="off";
                app.Desfase1EditField.Visible="off";
            end


        end

        % Callback function
        function Grafica2DropDownValueChanged(app, event)
            value = app.Grafica2DropDown.Value;
            %Opciones para la grafica 2
            if value==1%%Cuadrada
                app.Exponente2Switch.Visible="off";
                app.Exponente2SwitchLabel.Visible="off";

                app.Compleja2Switch.Visible="off";
                app.Compleja2SwitchLabel.Visible="off";

                app.Amplitud2EditField.Visible="on";
                app.Amplitud2EditFieldLabel.Visible="on";

                app.Ciclo2EditField.Visible="on";
                app.Ciclo2EditFieldLabel.Visible="on";

                app.Ancho2EditField.Visible="off";
                app.Ancho2EditFieldLabel.Visible="off";

                app.Base2EditField.Visible="off";
                app.Base2EditFieldLabel.Visible="off";

                app.Desfase2EditField.Visible="on";
                app.Desfase2EditFieldLabel.Visible="on";
            end
            if value==2%%Diente de sierra
                app.Exponente2Switch.Visible="off";
                app.Exponente2SwitchLabel.Visible="off";

                app.Compleja2Switch.Visible="off";
                app.Compleja2SwitchLabel.Visible="off";

                app.Amplitud2EditField.Visible="on";
                app.Amplitud2EditFieldLabel.Visible="on";

                app.Ciclo2EditField.Visible="off";
                app.Ciclo2EditFieldLabel.Visible="off";

                app.Ancho2EditField.Visible="on";
                app.Ancho2EditFieldLabel.Visible="on";

                app.Base2EditField.Visible="off";
                app.Base2EditFieldLabel.Visible="off";

                app.Desfase2EditField.Visible="on";
                app.Desfase2EditFieldLabel.Visible="on";
            end
            if value==3%%Senoidal
                app.Exponente2Switch.Visible="off";
                app.Exponente2SwitchLabel.Visible="off";

                app.Compleja2Switch.Visible="off";
                app.Compleja2SwitchLabel.Visible="off";

                app.Amplitud2EditField.Visible="on";
                app.Amplitud2EditFieldLabel.Visible="on";

                app.Ciclo2EditField.Visible="off";
                app.Ciclo2EditFieldLabel.Visible="off";

                app.Ancho2EditField.Visible="off";
                app.Ancho2EditFieldLabel.Visible="off";

                app.Base2EditField.Visible="off";
                app.Base2EditFieldLabel.Visible="off";

                app.Desfase2EditField.Visible="on";
                app.Desfase2EditFieldLabel.Visible="on";
            end
            if value==4%%Exponencial
                app.Exponente2Switch.Visible="on";
                app.Exponente2SwitchLabel.Visible="on";

                app.Compleja2Switch.Visible="off";
                app.Compleja2SwitchLabel.Visible="off";

                app.Amplitud2EditField.Visible="on";
                app.Amplitud2EditFieldLabel.Visible="on";

                app.Ciclo2EditField.Visible="off";
                app.Ciclo2EditFieldLabel.Visible="off";

                app.Ancho2EditField.Visible="off";
                app.Ancho2EditFieldLabel.Visible="off";

                app.Base2EditField.Visible="on";
                app.Base2EditFieldLabel.Visible="on";

                app.Desfase2EditField.Visible="off";
                app.Desfase2EditFieldLabel.Visible="off";
            end
            if value==5%%Exponencial compleja
                app.Exponente2Switch.Visible="off";
                app.Exponente2SwitchLabel.Visible="off";

                app.Compleja2Switch.Visible="on";
                app.Compleja2SwitchLabel.Visible="on";

                app.Amplitud2EditField.Visible="on";
                app.Amplitud2EditFieldLabel.Visible="on";

                app.Ciclo2EditField.Visible="off";
                app.Ciclo2EditFieldLabel.Visible="off";

                app.Ancho2EditField.Visible="off";
                app.Ancho2EditFieldLabel.Visible="off";

                app.Base2EditField.Visible="off";
                app.Base2EditFieldLabel.Visible="off";

                app.Desfase2EditField.Visible="on";
                app.Desfase2EditFieldLabel.Visible="on";
            end
            if value==6%%Escalon
                app.Exponente2Switch.Visible="off";
                app.Exponente2SwitchLabel.Visible="off";

                app.Compleja2Switch.Visible="off";
                app.Compleja2SwitchLabel.Visible="off";

                app.Amplitud2EditField.Visible="on";
                app.Amplitud2EditFieldLabel.Visible="on";

                app.Ciclo2EditField.Visible="off";
                app.Ciclo2EditFieldLabel.Visible="off";

                app.Ancho2EditField.Visible="off";
                app.Ancho2EditFieldLabel.Visible="off";

                app.Base2EditField.Visible="off";
                app.Base2EditFieldLabel.Visible="off";

                app.Desfase2EditField.Visible="on";
                app.Desfase2EditFieldLabel.Visible="on";
            end
            if value==7%Impulso
                app.Exponente2Switch.Visible="off";
                app.Exponente2SwitchLabel.Visible="off";

                app.Compleja2Switch.Visible="off";
                app.Compleja2SwitchLabel.Visible="off";

                app.Amplitud2EditField.Visible="on";
                app.Amplitud2EditFieldLabel.Visible="on";

                app.Ciclo2EditField.Visible="off";
                app.Ciclo2EditFieldLabel.Visible="off";

                app.Ancho2EditField.Visible="off";
                app.Ancho2EditFieldLabel.Visible="off";

                app.Base2EditField.Visible="off";
                app.Base2EditFieldLabel.Visible="off";

                app.Desfase2EditField.Visible="on";
                app.Desfase2EditFieldLabel.Visible="on";
            end
            if value==8%rampa
                app.Exponente2Switch.Visible="off";
                app.Exponente2SwitchLabel.Visible="off";

                app.Compleja2Switch.Visible="off";
                app.Compleja2SwitchLabel.Visible="off";

                app.Amplitud2EditField.Visible="on";
                app.Amplitud2EditFieldLabel.Visible="on";

                app.Ciclo2EditField.Visible="off";
                app.Ciclo2EditFieldLabel.Visible="off";

                app.Ancho2EditField.Visible="off";
                app.Ancho2EditFieldLabel.Visible="off";

                app.Base2EditField.Visible="off";
                app.Base2EditFieldLabel.Visible="off";

                app.Desfase2EditField.Visible="on";
                app.Desfase2EditFieldLabel.Visible="on";
            end
            if value==9%Rectangular
                app.Exponente2Switch.Visible="off";
                app.Exponente2SwitchLabel.Visible="off";

                app.Amplitud2EditField.Visible="on";
                app.Amplitud2EditFieldLabel.Visible="on";

                app.Ciclo2EditField.Visible="off";
                app.Ciclo2EditFieldLabel.Visible="off";

                app.Ancho2EditField.Visible="off";
                app.Ancho2EditFieldLabel.Visible="off";

                app.Base2EditField.Visible="off";
                app.Base2EditFieldLabel.Visible="off";

                app.Desfase2EditField.Visible="off";
                app.Desfase2EditFieldLabel.Visible="off";

                app.Compleja2Switch.Visible="off";
                app.Compleja2SwitchLabel.Visible="off";
            end
            if value==10%triangular
                app.Exponente2Switch.Visible="off";
                app.Exponente2SwitchLabel.Visible="off";

                app.Compleja2Switch.Visible="off";
                app.Compleja2SwitchLabel.Visible="off";

                app.Amplitud2EditField.Visible="on";
                app.Amplitud2EditFieldLabel.Visible="on";

                app.Ciclo2EditField.Visible="off";
                app.Ciclo2EditFieldLabel.Visible="off";

                app.Ancho2EditField.Visible="off";
                app.Ancho2EditFieldLabel.Visible="off";

                app.Base2EditField.Visible="off";
                app.Base2EditFieldLabel.Visible="off";

                app.Desfase2EditField.Visible="off";
                app.Desfase2EditFieldLabel.Visible="off";
            end
        end

        % Value changed function: Grafica2DropDown
        function Grafica2DropDownValueChanged2(app, event)
            %%Cambios de valor en el dropdown de la primera grafica
            value = app.Grafica2DropDown.Value;

            if value==1 %%Si es grafica Cuadrada
                app.Exponente2Switch.Visible="off";
                app.Exponente2SwitchLabel.Visible="off";

                app.Compleja2Switch.Visible="off";
                app.Compleja2SwitchLabel.Visible="off";

                app.Amplitud2EditField.Visible="on";
                app.Amplitud2EditFieldLabel.Visible="on";

                app.Ciclo2EditField.Visible="on";
                app.Ciclo2EditFieldLabel.Visible="on";

                app.Ancho2EditField.Visible="off";
                app.Ancho2EditFieldLabel.Visible="off";

                app.Base2EditField.Visible="off";
                app.Base2EditFieldLabel.Visible="off";

                app.Desfase2EditField.Visible="on";
                app.Desfase2EditField.Visible="on";
            end

            if value==2%%Si es grafica diente de sierra
                app.Exponente2Switch.Visible="off";
                app.Exponente2SwitchLabel.Visible="off";

                app.Compleja2Switch.Visible="off";
                app.Compleja2SwitchLabel.Visible="off";

                app.Amplitud2EditField.Visible="on";
                app.Amplitud2EditFieldLabel.Visible="on";

                app.Ciclo2EditField.Visible="off";
                app.Ciclo2EditFieldLabel.Visible="off";

                app.Ancho2EditField.Visible="on";
                app.Ancho2EditFieldLabel.Visible="on";

                app.Base2EditField.Visible="off";
                app.Base2EditFieldLabel.Visible="off";

                app.Desfase2EditField.Visible="on";
                app.Desfase2EditField.Visible="on";
            end

            if value==3%%Si es grafica Senoidal
                app.Exponente2Switch.Visible="off";
                app.Exponente2SwitchLabel.Visible="off";

                app.Compleja2Switch.Visible="off";
                app.Compleja2SwitchLabel.Visible="off";

                app.Amplitud2EditField.Visible="on";
                app.Amplitud2EditFieldLabel.Visible="on";

                app.Ciclo2EditField.Visible="off";
                app.Ciclo2EditFieldLabel.Visible="off";

                app.Ancho2EditField.Visible="off";
                app.Ancho2EditFieldLabel.Visible="off";

                app.Base2EditField.Visible="off";
                app.Base2EditFieldLabel.Visible="off";

                app.Desfase2EditField.Visible="on";
                app.Desfase2EditField.Visible="on";
            end

            if value==4%%Si es grafica exponencial
                app.Exponente2Switch.Visible="on";
                app.Exponente2SwitchLabel.Visible="on";

                app.Compleja2Switch.Visible="off";
                app.Compleja2SwitchLabel.Visible="off";

                app.Amplitud2EditField.Visible="on";
                app.Amplitud2EditFieldLabel.Visible="on";

                app.Ciclo2EditField.Visible="off";
                app.Ciclo2EditFieldLabel.Visible="off";

                app.Ancho2EditField.Visible="off";
                app.Ancho2EditFieldLabel.Visible="off";

                app.Base2EditField.Visible="on";
                app.Base2EditFieldLabel.Visible="on";

                app.Desfase2EditField.Visible="off";
                app.Desfase2EditField.Visible="off";
            end

            if value==5%%Si es grafica exponencial compleja
                app.Exponente2Switch.Visible="off";
                app.Exponente2SwitchLabel.Visible="off";

                app.Compleja2Switch.Visible="on";
                app.Compleja2SwitchLabel.Visible="on";

                app.Amplitud2EditField.Visible="on";
                app.Amplitud2EditFieldLabel.Visible="on";

                app.Ciclo2EditField.Visible="off";
                app.Ciclo2EditFieldLabel.Visible="off";

                app.Ancho2EditField.Visible="off";
                app.Ancho2EditFieldLabel.Visible="off";

                app.Base2EditField.Visible="off";
                app.Base2EditFieldLabel.Visible="off";

                app.Desfase2EditField.Visible="off";
                app.Desfase2EditFieldLabel.Visible="off";

            end
            if value==6%%Escalon
                app.Exponente2Switch.Visible="off";
                app.Exponente2SwitchLabel.Visible="off";

                app.Compleja2Switch.Visible="off";
                app.Compleja2SwitchLabel.Visible="off";

                app.Amplitud2EditField.Visible="on";
                app.Amplitud2EditFieldLabel.Visible="on";

                app.Ciclo2EditField.Visible="off";
                app.Ciclo2EditFieldLabel.Visible="off";

                app.Ancho2EditField.Visible="off";
                app.Ancho2EditFieldLabel.Visible="off";

                app.Base2EditField.Visible="off";
                app.Base2EditFieldLabel.Visible="off";

                app.Desfase2EditField.Visible="on";
                app.Desfase2EditField.Visible="on";
            end

            if value==7%%Impulso
                app.Exponente2Switch.Visible="off";
                app.Exponente2SwitchLabel.Visible="off";

                app.Compleja2Switch.Visible="off";
                app.Compleja2SwitchLabel.Visible="off";

                app.Amplitud2EditField.Visible="on";
                app.Amplitud2EditFieldLabel.Visible="on";

                app.Ciclo2EditField.Visible="off";
                app.Ciclo2EditFieldLabel.Visible="off";

                app.Ancho2EditField.Visible="off";
                app.Ancho2EditFieldLabel.Visible="off";

                app.Base2EditField.Visible="off";
                app.Base2EditFieldLabel.Visible="off";

                app.Desfase2EditField.Visible="on";
                app.Desfase2EditField.Visible="on";
            end

            if value==8%%Rampa
                app.Exponente2Switch.Visible="off";
                app.Exponente2SwitchLabel.Visible="off";

                app.Compleja2Switch.Visible="off";
                app.Compleja2SwitchLabel.Visible="off";

                app.Amplitud2EditField.Visible="on";
                app.Amplitud2EditFieldLabel.Visible="on";

                app.Ciclo2EditField.Visible="off";
                app.Ciclo2EditFieldLabel.Visible="off";

                app.Ancho2EditField.Visible="off";
                app.Ancho2EditFieldLabel.Visible="off";

                app.Base2EditField.Visible="off";
                app.Base2EditFieldLabel.Visible="off";

                app.Desfase2EditField.Visible="on";
                app.Desfase2EditField.Visible="on";
            end

            if value==9%%Rectangular
                app.Exponente2Switch.Visible="off";
                app.Exponente2SwitchLabel.Visible="off";

                app.Compleja2Switch.Visible="off";
                app.Compleja2SwitchLabel.Visible="off";

                app.Amplitud2EditField.Visible="on";
                app.Amplitud2EditFieldLabel.Visible="on";

                app.Ciclo2EditField.Visible="off";
                app.Ciclo2EditFieldLabel.Visible="off";

                app.Ancho2EditField.Visible="off";
                app.Ancho2EditFieldLabel.Visible="off";

                app.Base2EditField.Visible="off";
                app.Base2EditFieldLabel.Visible="off";

                app.Desfase2EditField.Visible="off";
                app.Desfase2EditField.Visible="off";
            end

            if value==10%%Triangular
                app.Exponente2Switch.Visible="off";
                app.Exponente2SwitchLabel.Visible="off";

                app.Compleja2Switch.Visible="off";
                app.Compleja2SwitchLabel.Visible="off";

                app.Amplitud2EditField.Visible="on";
                app.Amplitud2EditFieldLabel.Visible="on";

                app.Ciclo2EditField.Visible="off";
                app.Ciclo2EditFieldLabel.Visible="off";

                app.Ancho2EditField.Visible="off";
                app.Ancho2EditFieldLabel.Visible="off";

                app.Base2EditField.Visible="off";
                app.Base2EditFieldLabel.Visible="off";

                app.Desfase2EditField.Visible="off";
                app.Desfase2EditField.Visible="off";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.GraficarButton = uibutton(app.Tab, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.EscalamientoEditFieldLabel = uilabel(app.Tab);
            app.EscalamientoEditFieldLabel.Text = 'Escalamiento';
            app.EscalamientoEditField = uieditfield(app.Tab, 'numeric');
            app.OperacionDropDownLabel = uilabel(app.Tab);
            app.OperacionDropDownLabel.Text = 'Operacion';
            app.OperacionDropDown = uidropdown(app.Tab);
            app.OperacionDropDown.Items = {'Seleccionar', 'Escalamiento', 'Suma', 'Multiplicacion', 'Diferenciacion', 'Integracion', 'Escalamiento de T', 'Reflexión', 'Corrimiento'};
            app.OperacionDropDown.ItemsData = [0 1 2 3 4 5 6 7 8 9];
            app.OperacionDropDown.ValueChangedFcn = createCallbackFcn(app, @OperacionDropDownValueChanged, true);
            app.OperacionDropDown.Value = 0;
            app.Grafica1DropDownLabel = uilabel(app.Tab);
            app.Grafica1DropDownLabel.Text = 'Grafica 1';
            app.Grafica1DropDown = uidropdown(app.Tab);
            app.Grafica1DropDown.Items = {'Selecciona', 'Cuadrada', 'Diente de sierra', 'Senoidal', 'Exponencial', 'E. Compleja', 'Escalon', 'Impulso', 'Rampa', 'Rectangular', 'Triangular'};
            app.Grafica1DropDown.ItemsData = [0 1 2 3 4 5 6 7 8 9 10];
            app.Grafica1DropDown.ValueChangedFcn = createCallbackFcn(app, @Grafica1DropDownValueChanged, true);
            app.Grafica1DropDown.Value = 0;
            app.ExponenteSwitchLabel = uilabel(app.Tab);
            app.ExponenteSwitchLabel.Text = 'Exponente';
            app.ExponenteSwitch = uiswitch(app.Tab, 'slider');
            app.ExponenteSwitch.Items = {'-', '+'};
            app.ExponenteSwitch.ItemsData = [0 1];
            app.ExponenteSwitch.Value = 0;
            app.Exponente2SwitchLabel = uilabel(app.Tab);
            app.Exponente2SwitchLabel.Text = 'Exponente 2';
            app.Exponente2Switch = uiswitch(app.Tab, 'slider');
            app.Exponente2Switch.Items = {'-', '+'};
            app.Exponente2Switch.ItemsData = {'0', '1'};
            app.Exponente2Switch.Value = '0';
            app.Grafica2DropDownLabel = uilabel(app.Tab);
            app.Grafica2DropDownLabel.Text = 'Grafica2';
            app.Grafica2DropDown = uidropdown(app.Tab);
            app.Grafica2DropDown.Items = {'Selecciona', 'Cuadrada', 'Diente de sierra', 'Senoidal', 'Exponencial', 'E. Compleja', 'Escalon', 'Impulso', 'Rampa', 'Rectangular', 'Triangular'};
            app.Grafica2DropDown.ItemsData = [0 1 2 3 4 5 6 7 8 9 10];
            app.Grafica2DropDown.ValueChangedFcn = createCallbackFcn(app, @Grafica2DropDownValueChanged2, true);
            app.Grafica2DropDown.Value = 0;
            app.FsEditFieldLabel = uilabel(app.Tab);
            app.FsEditFieldLabel.Text = 'Fs';
            app.FsEditField = uieditfield(app.Tab, 'numeric');
            app.F0EditFieldLabel = uilabel(app.Tab);
            app.F0EditFieldLabel.Text = 'F0';
            app.F0EditField = uieditfield(app.Tab, 'numeric');
            app.PeriodosEditFieldLabel = uilabel(app.Tab);
            app.PeriodosEditFieldLabel.Text = 'Periodos';
            app.PeriodosEditField = uieditfield(app.Tab, 'numeric');
            app.Base1EditFieldLabel = uilabel(app.Tab);
            app.Base1EditFieldLabel.Text = 'Base1';
            app.Base1EditField = uieditfield(app.Tab, 'numeric');
            app.Base2EditFieldLabel = uilabel(app.Tab);
            app.Base2EditFieldLabel.Text = 'Base2';
            app.Base2EditField = uieditfield(app.Tab, 'numeric');
            app.TiempoIniEditFieldLabel = uilabel(app.Tab);
            app.TiempoIniEditFieldLabel.Text = 'Tiempo  Ini';
            app.TiempoIniEditField = uieditfield(app.Tab, 'numeric');
            app.TiempoFinEditFieldLabel = uilabel(app.Tab);
            app.TiempoFinEditFieldLabel.Text = 'Tiempo  Fin';
            app.TiempoFinEditField = uieditfield(app.Tab, 'numeric');
            app.Compleja1Switch = uiswitch(app.Tab, 'slider');
            app.Compleja1Switch.Items = {'Img', 'R'};
            app.Compleja1Switch.ItemsData = {'0', '1'};
            app.Compleja1Switch.Value = '0';
            app.Compleja1SwitchLabel = uilabel(app.Tab);
            app.Compleja1SwitchLabel.Text = 'Compleja 1';
            app.Compleja2Switch = uiswitch(app.Tab, 'slider');
            app.Compleja2Switch.Items = {'Img', 'R'};
            app.Compleja2Switch.ItemsData = {'0', '1'};
            app.Compleja2Switch.Value = '0';
            app.Compleja2SwitchLabel = uilabel(app.Tab);
            app.Compleja2SwitchLabel.Text = 'Compleja 2';
            app.Amplitud2EditFieldLabel = uilabel(app.Tab);
            app.Amplitud2EditFieldLabel.Text = 'Amplitud2';
            app.Amplitud2EditField = uieditfield(app.Tab, 'numeric');
            app.Amplitud1EditFieldLabel = uilabel(app.Tab);
            app.Amplitud1EditFieldLabel.Text = 'Amplitud1';
            app.Amplitud1EditField = uieditfield(app.Tab, 'numeric');
            app.Ancho1EditFieldLabel = uilabel(app.Tab);
            app.Ancho1EditFieldLabel.Text = 'Ancho1';
            app.Ancho1EditField = uieditfield(app.Tab, 'numeric');
            app.Desfase1EditFieldLabel = uilabel(app.Tab);
            app.Desfase1EditFieldLabel.Text = 'Desfase1';
            app.Desfase1EditField = uieditfield(app.Tab, 'numeric');
            app.Ancho2EditFieldLabel = uilabel(app.Tab);
            app.Ancho2EditFieldLabel.Text = 'Ancho2';
            app.Ancho2EditField = uieditfield(app.Tab, 'numeric');
            app.Desfase2EditFieldLabel = uilabel(app.Tab);
            app.Desfase2EditFieldLabel.Text = 'Desfase2';
            app.Desfase2EditField = uieditfield(app.Tab, 'numeric');
            app.Ciclo1EditFieldLabel = uilabel(app.Tab);
            app.Ciclo1EditFieldLabel.Text = 'Ciclo1';
            app.Ciclo1EditField = uieditfield(app.Tab, 'numeric');
            app.Ciclo2EditFieldLabel = uilabel(app.Tab);
            app.Ciclo2EditFieldLabel.Text = 'Ciclo2';
            app.Ciclo2EditField = uieditfield(app.Tab, 'numeric');
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.UIAxes2 = uiaxes(app.Tab2);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.Tab2);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.Tab2);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.Tab2);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Practica_4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end