classdef VarInd < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        OKButton                      matlab.ui.control.Button
        EditField3                    matlab.ui.control.NumericEditField
        EditField                     matlab.ui.control.NumericEditField
        CorrimientoentiempoCheckBox   matlab.ui.control.CheckBox
        ReflexionCheckBox             matlab.ui.control.CheckBox
        EscalamientodetiempoCheckBox  matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            app.EditField.Enable = "off";
            app.EditField3.Enable = "off";
            app.CallingApp = caller;
        end

        % Value changed function: EscalamientodetiempoCheckBox
        function EscalamientodetiempoCheckBoxValueChanged(app, event)
            value = app.EscalamientodetiempoCheckBox.Value;
            if value == 1
                app.EditField.Enable ="on";
            else
                app.EditField.Enable ="off";
            end
        
        end

        % Value changed function: CorrimientoentiempoCheckBox
        function CorrimientoentiempoCheckBoxValueChanged(app, event)
            value = app.CorrimientoentiempoCheckBox.Value;
             if value == 1
                app.EditField3.Enable ="on";
             else
                app.EditField3.Enable ="off";
            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
               % Call main app's public function
   valor = app.EditField.Value;
   if valor == 0
       valor = 1;
   end
   updateplot(app.CallingApp,valor,app.ReflexionCheckBox.Value,app.EditField3.Value);

   % Delete the dialog box  
   delete(app)   
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EscalamientodetiempoCheckBox = uicheckbox(app.UIFigure);
            app.EscalamientodetiempoCheckBox.ValueChangedFcn = createCallbackFcn(app, @EscalamientodetiempoCheckBoxValueChanged, true);
            app.EscalamientodetiempoCheckBox.Text = 'Escalamiento de tiempo';
            app.ReflexionCheckBox = uicheckbox(app.UIFigure);
            app.ReflexionCheckBox.Text = 'Reflexion';
            app.CorrimientoentiempoCheckBox = uicheckbox(app.UIFigure);
            app.CorrimientoentiempoCheckBox.ValueChangedFcn = createCallbackFcn(app, @CorrimientoentiempoCheckBoxValueChanged, true);
            app.CorrimientoentiempoCheckBox.Text = 'Corrimiento en tiempo ';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField3 = uieditfield(app.UIFigure, 'numeric');
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VarInd(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end