classdef Practica_8_si < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        LagSpinner                  matlab.ui.control.Spinner
        LagSpinnerLabel             matlab.ui.control.Label
        Tiempofin2EditField         matlab.ui.control.NumericEditField
        Tiempofin2EditFieldLabel    matlab.ui.control.Label
        Tiempoini2EditField         matlab.ui.control.NumericEditField
        Tiempoini2EditFieldLabel    matlab.ui.control.Label
        Fs2EditField                matlab.ui.control.NumericEditField
        Fs2EditFieldLabel           matlab.ui.control.Label
        TabGroup                    matlab.ui.container.TabGroup
        GeneradordesealesTab        matlab.ui.container.Tab
        VarIndButton                matlab.ui.control.Button
        Button2                     matlab.ui.control.Button
        F02EditField                matlab.ui.control.NumericEditField
        F02EditFieldLabel           matlab.ui.control.Label
        Funcion2Switch              matlab.ui.control.Switch
        Funcion2SwitchLabel         matlab.ui.control.Label
        Funcion1Switch              matlab.ui.control.Switch
        Funcion1SwitchLabel         matlab.ui.control.Label
        NaturalezaSwitch            matlab.ui.control.Switch
        NaturalezaSwitchLabel       matlab.ui.control.Label
        Ciclo2EditField             matlab.ui.control.NumericEditField
        Ciclo2EditFieldLabel        matlab.ui.control.Label
        Ciclo1EditField             matlab.ui.control.NumericEditField
        Ciclo1EditFieldLabel        matlab.ui.control.Label
        Desfase2EditField           matlab.ui.control.NumericEditField
        Desfase2EditFieldLabel      matlab.ui.control.Label
        Ancho2EditField             matlab.ui.control.NumericEditField
        Ancho2EditFieldLabel        matlab.ui.control.Label
        Desfase1EditField           matlab.ui.control.NumericEditField
        Desfase1EditFieldLabel      matlab.ui.control.Label
        Ancho1EditField             matlab.ui.control.NumericEditField
        Ancho1EditFieldLabel        matlab.ui.control.Label
        Amplitud1EditField          matlab.ui.control.NumericEditField
        Amplitud1EditFieldLabel     matlab.ui.control.Label
        Amplitud2EditField          matlab.ui.control.NumericEditField
        Amplitud2EditFieldLabel     matlab.ui.control.Label
        Compleja2SwitchLabel        matlab.ui.control.Label
        Compleja2Switch             matlab.ui.control.Switch
        Compleja1SwitchLabel        matlab.ui.control.Label
        Compleja1Switch             matlab.ui.control.Switch
        TiempoFinEditField          matlab.ui.control.NumericEditField
        TiempoFinEditFieldLabel     matlab.ui.control.Label
        TiempoIniEditField          matlab.ui.control.NumericEditField
        TiempoIniEditFieldLabel     matlab.ui.control.Label
        Base2EditField              matlab.ui.control.NumericEditField
        Base2EditFieldLabel         matlab.ui.control.Label
        Base1EditField              matlab.ui.control.NumericEditField
        Base1EditFieldLabel         matlab.ui.control.Label
        F0EditField                 matlab.ui.control.NumericEditField
        F0EditFieldLabel            matlab.ui.control.Label
        FsEditField                 matlab.ui.control.NumericEditField
        FsEditFieldLabel            matlab.ui.control.Label
        Grafica2DropDown            matlab.ui.control.DropDown
        Grafica2DropDownLabel       matlab.ui.control.Label
        Exponente2Switch            matlab.ui.control.Switch
        Exponente2SwitchLabel       matlab.ui.control.Label
        ExponenteSwitch             matlab.ui.control.Switch
        ExponenteSwitchLabel        matlab.ui.control.Label
        Grafica1DropDown            matlab.ui.control.DropDown
        Grafica1DropDownLabel       matlab.ui.control.Label
        OperacionDropDown           matlab.ui.control.DropDown
        OperacionDropDownLabel      matlab.ui.control.Label
        EscalamientoEditField       matlab.ui.control.NumericEditField
        EscalamientoEditFieldLabel  matlab.ui.control.Label
        GraficarButton              matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
        SealesintroducidasTab       matlab.ui.container.Tab
        UIAxes4                     matlab.ui.control.UIAxes
        UIAxes3                     matlab.ui.control.UIAxes
        SealescorrelacionadasTab    matlab.ui.container.Tab
        UIAxes7                     matlab.ui.control.UIAxes
        UIAxes6                     matlab.ui.control.UIAxes
        Button                      matlab.ui.control.Button
        UIAxes5                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Funcion; % guardar funcion generada
        intervalo;
        factor_escalamiento
        reflex
        corrimiento
        DialogApp 
    end
    
    methods (Access = public)
        
        function updateplot(app,escalamiento,reflex,corrimiento)
            app.factor_escalamiento = escalamiento;
            app.reflex = reflex;
            app.corrimiento = corrimiento; 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.EscalamientoEditField.Visible="off";
            app.EscalamientoEditFieldLabel.Visible="off";

            app.OperacionDropDown.Visible="off";
            app.OperacionDropDownLabel.Visible="off";

            app.Grafica2DropDown.Visible="on";
            app.Grafica2DropDownLabel.Visible="on";

            app.ExponenteSwitch.Visible="off";
            app.ExponenteSwitchLabel.Visible="off";

            app.Exponente2Switch.Visible="off";
            app.Exponente2SwitchLabel.Visible="off";

            app.Compleja1Switch.Visible="off";
            app.Compleja1SwitchLabel.Visible="off";

            app.Compleja2Switch.Visible="off";
            app.Compleja2SwitchLabel.Visible="off";

            app.Amplitud1EditField.Visible="off";
            app.Amplitud1EditFieldLabel.Visible="off";
            
            app.Amplitud2EditField.Visible="off";
            app.Amplitud2EditFieldLabel.Visible="off";

            app.Ancho1EditField.Visible="off";
            app.Ancho1EditFieldLabel.Visible="off";

            app.Ancho2EditField.Visible="off";
            app.Ancho2EditFieldLabel.Visible="off";

            app.Desfase1EditField.Visible="off";
            app.Desfase1EditFieldLabel.Visible="off";

            app.Desfase2EditField.Visible="off";
            app.Desfase2EditFieldLabel.Visible="off";

            app.Base1EditField.Visible="off";
            app.Base1EditFieldLabel.Visible="off";

            app.Base2EditField.Visible="off";
            app.Base2EditFieldLabel.Visible="off";

            app.Ciclo1EditField.Visible="off";
            app.Ciclo1EditFieldLabel.Visible="off";

            app.Ciclo2EditField.Visible="off";
            app.Ciclo2EditFieldLabel.Visible="off";
            
            app.Ancho1EditField.Visible="off";
            app.Ancho1EditFieldLabel.Visible="off";

            app.Desfase1EditField.Visible="off";
            app.Desfase1EditFieldLabel.Visible="off";

            app.Base1EditField.Visible="off";
            app.Base1EditFieldLabel.Visible="off";

            app.Ciclo1EditField.Visible="off";
            app.Ciclo1EditFieldLabel.Visible="off";

            app.Ciclo2EditField.Visible="off";
            app.Ciclo2EditFieldLabel.Visible="off";

            app.Amplitud2EditField.Visible="off";
            app.Amplitud2EditFieldLabel.Visible="off";

            app.Ancho2EditField.Visible="off";
            app.Ancho2EditFieldLabel.Visible="off";

            app.Desfase2EditField.Visible="off";
            app.Desfase2EditFieldLabel.Visible="off";

            app.Base2EditField.Visible="off";
            app.Base2EditFieldLabel.Visible="off";

            app.Funcion1Switch.Visible="off";
            app.Funcion1SwitchLabel.Visible="off";

            app.Funcion2Switch.Visible="off";
            app.Funcion2SwitchLabel.Visible="off";


        end

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
            operacion = 0;
            graph = app.Grafica1DropDown.Value; %Opcion de grafica 1
            graph2 = app.Grafica2DropDown.Value;%Opcion de grafica 2

            A = app.Amplitud1EditField.Value; %%Amplitud 
            A2 = app.Amplitud2EditField.Value;%%Amplitud Grafica 2
                
            fs = app.FsEditField.Value;  %%Muestras por segundo Grafica 1
            fs2 = app.Fs2EditField.Value;%%Muestras por segundo Grafica 2

            t1 = app.TiempoIniEditField.Value;    %%Tiempo inicial grafica 1
            ti2 = app.Tiempoini2EditField.Value;  %%Tiempo inicial grafica 2

            f = app.F0EditField.Value;   %%frecuencia fundamental Grafica 1
            f2 = app.F02EditField.Value; %%frecuencia fundamental Grafica 2
            
            T = app.TiempoFinEditField.Value;    %% Tiempo final de grafica 1
            tf2 = app.Tiempofin2EditField.Value; %% Tiempo final Grafica 2

            t = t1: 1/fs: T;       %% Tiempos Grafica 1
            t_2 = ti2: 1/fs2: tf2; %% Tiempos Grafica 2
            
            
            width = app.Ancho1EditField.Value; %%Ancho Grafica 1
            width2 = app.Ancho2EditField.Value;%%Ancho Grafica 2
            des = app.Desfase1EditField.Value; %% Desfase Grafica 1
            des2 = app.Desfase2EditField.Value;%% Desfase Grafica 2
            B = app.Base1EditField.Value; %%Base de la funcion exponencial Grafica 1
            B2 = app.Base2EditField.Value;%%Base de la funcion exponencial Grafica 2
            duty = app.Ciclo1EditField.Value; %%Ciclo de trabajo Grafica 1
            duty2 = app.Ciclo2EditField.Value;%%Ciclo de trabajo Grafica 2
 
            L = app.LagSpinner.Value;  % Lag máximo

            %%=======================================================================
            %% =============================CORRELACIÓN==============================
            %%=======================================================================
            if operacion == 0

            %%Generar las señales
                %%Generar Señal 1
                    if graph == 1 %% señal cuadrada
                        sign1 = A.*square(2*pi*f*t+des, duty);
                    end
                    if graph == 2 %% Señal diente de sierra
                        sign1 = A.*sawtooth(2*pi*t*f+des, width);
                    end
                    if graph == 3 %% Señal senosoidal
                        sign1 = A.*sin(2*pi*f*t+des);
                    end
                    if graph == 4 %% señal exponencial
                        if app.ExponenteSwitch.Value == 0 %%Switch del exponente
                            sign1 = A.*B.^(-t);
                        end
                        if app.ExponenteSwitch.Value == 1
                            sign1 = A.*B.^(t);
                        end
                    end
                    if graph == 5 %% señal exponencial compleja
                        x = A.*exp(1i.*(f.*t+pi/4));
                        if app.Compleja1Switch.Value == '0'%compleja
                            sign1 = imag(x);
                        end
                        if app.Compleja1Switch.Value == '1'
                            sign1 = real(x);
                        end
                    end
                    if graph == 6 %%Señal de escalon
                        sign1 = A.*ones(1, length(t));
                        for i = 1: des+1-t1*fs
                            sign1(i) = 0;
                        end
                    end
                    if graph == 7 %%Señal de impulso
                        sign1 = zeros(length(t));
                        sign1(des-t1*fs) = A;
                    end
                    if graph == 8 %%Señal rampa
                        sign1 = A.*(t+des);
                    end
                    if graph == 9%%Señal rectangular
                        sign1 = A.*rectpuls(t+des, 1);
                    end
                    if graph == 10%Señal triangular
                         sign1 = A.*tripuls(t+des, 1);
                    end
                
                %%Generar Señal 2
                    if graph2 == 1 %% señal cuadrada
                        sign2 = A2.*square(2*pi*f2*t_2+des2, duty2);
                    end
                    if graph2 == 2 %% Señal diente de sierra
                        sign2 = A2.*sawtooth(2*pi*t_2*f2+des2, width2);
                    end
                    if graph2 == 3 %% Señal senosoidal
                        sign2 = A2.*sin(2*pi*f2*t_2+des2);
                    end
                    if graph2 == 4 %% señal exponencial
                        if app.Exponente2Switch.Value == '0'%negativa
                            sign2 = A2.*B2.^(-t_2);
                        end
                        if app.Exponente2Switch.Value == '1'%positiva
                            sign2 = A2.*B2.^(t_2);
                        end
                    end
                    if graph2 == 5%%Exponencial compleja
                        x = A2.*exp(1i.*(f2.*t_2+pi/4));%%Generamos funcion compleja
                        if app.Compleja1Switch.Value == '0'%compleja
                            sign2 = imag(x);
                        end
                        if app.Compleja1Switch.Value == '1'
                            sign2 = real(x);
                        end
                    end
                    if graph2 == 6%%Señal de escalon
                         sign2 = A2.*ones(1,length(t_2));
                       for i = 1:des2+1-ti2*fs2
                           sign2(i) = 0;
                       end
                    end
                    if graph2 == 7%%Señal de impulso
                        sign2 = zeros(length(t_2));
                        sign2(des2-ti2*fs) = A2;
                    end
                    if graph2 == 8%%Señal rampa
                        sign2 = A2.*(t_2+des2);
                    end
                    if graph2 == 9%%Señal rectangular
                        sign2 = A2.*rectpuls(t_2+des2, 1);
                    end
                    if graph2 == 10%%Señal triangular
                        sign2 = A2.*tripuls(t_2+des2, 1);
                    end
                
                % % %%Matriz de convolucion 
                % % %%Tengo que agregar la longitud, ya que la convolucion
                % % %%puede seguir pero para el ultimo n va a exceder el
                % % %%tamaño, si no lo pongo, va a quedar mal


                %%Plotear señales originales
                sign1_n = sign1;
                sign2_n = sign2;

                plot(app.UIAxes3, t, sign1_n)
                plot(app.UIAxes4, t_2, sign2_n)


            %%Corrigiendo señales
                % Generando nueva frecuencia de muestro para las 2 señales
                Fnt = 0;
                Fnn = 0;

                if(T > 1)
                    Fnt = 1;
                elseif(T > 0.1 && T <= 1)
                    Fnt = 0.1;
                elseif(T > 0.01 && T <= 0.1)
                    Fnt = 0.01;
                end
                
                if(tf2 > 1)
                    Fnn = 1;
                elseif(tf2 > 0.1 && tf2 <= 1)
                    Fnn = 0.1;
                elseif(tf2 > 0.01 && tf2 <= 0.1)
                    Fnn = 0.01;
                end

                Ff = min(Fnt, Fnn);  % Escogiendo la frecuencia mínima

                tn = t1: Ff: T;   % Nuevo tiempo para sign1
                nn = ti2: Ff: tf2;% Nuevo tiempo para sign2

                % Generando señal de ceros
                lif = min(t1, ti2); % Escogiendo tiempo inicial menor entre sign1 y sign2
                lsfm = min(T, tf2); % Escogiendo tiempo final menor entre sign1 y sign2
                lsf = max(T, tf2);  % Escogiendo tiempo final mayor entre sign1 y sign2
                
                S0 = zeros(1, max(length(tn), length(nn))); % Señal de ceros

                % Generando variable dependiente para la señal S0 
                if(length(tn) >= length(nn))
                    
                    Fx = tn;
                    % Fx = Ff.*Fx;
                else
                    Fx = nn;
                    S0 = zeros(1, max(length(tn), length(nn)));
                    % Fx = lsf - max(length(tn), length(nn)) + 1: lsf;
                    % S0 = zeros(1, min(length(tn), length(nn)) + lsf);
                    % Fx = Ff.*Fx;
                end

                %%Interpolacion de señales
                sign1_n = interp1(t, sign1, tn, 'spline');
                sign2_n = interp1(t_2, sign2, nn, 'spline');

                S0(find(Fx == ti2): find(Fx == tf2)) = sign2_n; % Almacenando la señal 2 en la señal de ceros

                % Hacemos que las señales sean del mismo tamaño en caso de no serlo
                    if(length(sign1_n) < length(sign2_n))
                        
                        s_aux = zeros(1, length(nn));
                        s_aux(find(Fx == tn(1)): find(Fx == tn(end))) = sign1_n;
                        tn = nn;
                        sign1_n = s_aux;
                    end


            %%Correlacion de MATLAB
                tic;
                    [x, Lag] = xcorr(sign1_n, S0, L, "normalized");
                    plot(app.UIAxes5, Lag, x)
                tiempo = toc;

                disp("Correlacion de Matlab: ")
                display(tiempo);

                % Ploteo de señales corregidas y correlacionadas
                plot(app.UIAxes6, tn, sign1_n)
                plot(app.UIAxes7, Fx, S0)

                % Ploteo de señal correlacionada por MATLAB
                plot(app.UIAxes5, Lag, x);

                    % tin=union(t, t_2);
                    % 
                    % sign1=interp1(t, sign1,tin, 'linear','extrap');
                    % sign2=interp1(t_2, sign2,tin, 'linear','extrap');
                    % 
                    % tic;
                    %     signalauto= xcorr(sign1,sign2, 6, "normalized");
                    % tiempo = toc;
                    % disp("Funcion de Matlab: ")
                    % display(tiempo);
                

            %%Correlacion desarrollada
                tic;
                    % Algoritmo
                    matrix = zeros(L*2 + 1, length(Fx));
    
                    for l = 1: L*2 + 1
                        aux = zeros(1, length(Fx));
    
                        for n = 1: length(Fx) 
                            u = n - (l - (L + 1)); % n - l
                            Sobrepasa = 1;
                            
                            if(u > length(Fx) || u < 1)
                                Sobrepasa = 0;
                                u = 1;
                            end
    
                            matrix(l, n) = sign1_n(n)*S0(u)*Sobrepasa; % Y(n)H(n - l)
                        end
                    end
    

                    forma=size(matrix);
                    Fila = forma(1);
                    Columna = forma(2);
                    signal=zeros(1, Fila);
    
                    for i = 1: Fila %Iteramos en columnas
                        sum = 0;
                        
                        for j = 1: Columna %Hacemos la suma de todas las filas
                            sum = sum + matrix(i, j);
                        end
                        signal(i) = sum; % Signal = Sumatoria(Y(n)H(n - l))
                    end

                    %%Normalizando señal
                    ValorMaximo_s = max(signal);
                    ValorMaximo_a = max(x);
                    N = ValorMaximo_s / ValorMaximo_a; % Regla de tres
                    signalCorr = signal;
                    signalCorr = (1 / N).*signalCorr; % Señal normalizada
                tiempo = toc;

                text_title="Correlacion personal";
                disp("Correlacion personal: ")
                display(tiempo);            
            
                % Ploteo de señal correlacionada por MATLAB
                plot(app.UIAxes, Lag, signalCorr)
            
            end


            %%=======================================================================
            %%========================Termina convolucion===========================
            %% =======================================================================
            

            if operacion==1 %%Escalamiento
                t=t1:1/fs:T-1/fs;
                esc=app.EscalamientoEditField.Value;
                if graph==1%%onda cuadrada
                    signal=esc.*A.*square(2*pi*f*t+des,duty);%% 2 por pi por la freq por el t mas un desfase
                     text_title='Señal de onda cuadrada con amplitud variable';

                end
                if graph==2%%diente de sierra
                    signal=esc.*A.*sawtooth(2*pi*t+des,width);
                    text_title='Señal diente de sierra con amplitud variable';
                end
                
                if graph==3%%senoidal
                    signal=esc.*A.*sin(2*pi*f*t+des);
                    text_title='Señal senoidal con amplitud variable';
                end
                if graph==4%%Señal exponencial
                
                    if app.ExponenteSwitch.Value==0 %%Switch del exponente
                        signal=esc.*A.*B.^(-t);
                    end
                    if app.ExponenteSwitch.Value==1
                        signal=esc.*A.*B.^(t);
                    end
                    text_title='Señal Exponencial';
                end

                if graph==5%%Exponencial compleja
              
                    x=esc.*A.*exp(1i.*(f.*t+pi/4));
                    if app.Compleja1Switch.Value=='0' %0 de imaginaria
                        signal=imag(x);
                    end
                    if app.Compleja1Switch.Value=='1' %1 de real
                        signal=real(x);
                    end
  
                    text_title='Señal exponencial compleja';

                end

                if graph==6%% Señal escalon
                    signal=esc.*ones(1,length(t));
                   for i=1:des+1-t1*fs
                       signal(i)=0;
                   end
                    text_title='Señal Escalon unitario';
                end

                if graph==7%%Impulso
                    signal=zeros(size(t));
                    signal(des+1-t1*fs)=A*esc;
                    text_title='Señal Impulso Unitario';
                end

                if graph==8%%Rampa
                    signal=esc.*A.*(t+des);
                    text_title='Señal Rampa';
                end

                if graph==9%% Rectangular
                    %<-------------------------------------------------------------------------------------------------------------------------------
                    signal=esc.*A.*rectpuls(t+des,1);
                    text_title='Señal rectangular';
                end

                if graph==10%%Triangular
                
                    signal=esc.*A.*tripuls(t+des,1);
                    text_title='Señal Triangular';

                end


            end%%Escalamiento end
%%========================================================================
            if operacion==2||operacion==3%Suma de funciones y multiplicacion de funciones
                t=t1:1/fs:T-1/fs;
                graph2=app.Grafica2DropDown.Value;
                A2=app.Amplitud2EditField.Value;%%Amplitud de la grafica 2
                duty2=app.Ciclo2EditField.Value;
                width2=app.Ancho2EditField.Value;
                des2=app.Desfase2EditField.Value;
                B2=app.Base2EditField.Value;
                f2=app.F02EditField.Value;
                %%Primero determinamos la grafica 1
             

                if app.Funcion1Switch.Value=='0'%%Generar funcion
                    if graph==1
                        sign1=A.*square(2*pi*f*t        +des,duty);
                    end
                    if graph==2
                        sign1=A.*sawtooth(2*pi*t+des,width);
                    end
                    if graph==3
                        sign1=A.*sin(2*pi*f*t+des);
                    end
                    if graph==4
                        if app.ExponenteSwitch.Value==0 %%Switch del exponente
                            sign1=A.*B.^(-t);
                        end
                        if app.ExponenteSwitch.Value==1
                            sign1=A.*B.^(t);
                        end
                    end
                    if graph==5
                        x=A.*exp(1i.*(f.*t+pi/4));
                        if app.Compleja1Switch.Value=='0'%compleja
                            sign1=imag(x);
                        end
                        if app.Compleja1Switch.Value=='1'
                            sign1=real(x);
                            
                        end
    
                    end
                    if graph==6%%Señal de escalon
                         sign1=A.*ones(1,length(t));
                       for i=1:des+1-t1*fs
                           sign1(i)=0;
                       end
                        
                    end
                    if graph==7%%Señal de impulso
                        sign1=zeros(length(t));
                        sign1(des-t1*fs)=A;
                    end
                    if graph==8%%Señal rampa
                        sign1=A.*(t+des);
                        
                    end
                    if graph==9%%Señal rectangular
                        sign1=A.*rectpuls(t+des,1);
                    end
                    if graph==10%Señal triangular
                         sign1=A.*tripuls(t+des,1);
    
                    end
                end
                if app.Funcion1Switch.Value=='1'
                    sign1=A.*app.Funcion;
                end
                %%Determinamos la grafica 2


                if app.Funcion2Switch.Value==0%Generar funcion
                    if graph2==1
                        sign2=A2.*square(2*pi*f2*t+des2,duty2);
                    end
                    if graph2==2
                        sign2=A2.*sawtooth(2*pi*t+des2,width2);
                    end
                    if graph2==3
                        sign2=A2.*sin(2*pi*f2*t+des2);
                    end
                    
                    if graph2==4
                        if app.Exponente2Switch.Value=='0'%negativa
                            sign2=A2.*B2.^(-t);
                        end
                        if app.Exponente2Switch.Value=='1'%positiva
                            sign2=A2.*B2.^(t);
                        end
                    end
                    if graph2==5%%Exponencial compleja
                        x=A2.*exp(1i.*(f2.*t+pi/4));%%Generamos funcion compleja
                        if app.Compleja1Switch.Value=='0'%compleja
                            sign2=imag(x);
                        end
                        if app.Compleja1Switch.Value=='1'
                            sign2=real(x);
                        end
                        %%checar arriba
                    end
                    if graph2==6%%Señal de escalon
                         sign2=A2.*ones(1,length(t));
                       for i=1:des2+1-t1*fs
                           sign2(i)=0;
                       end
                    end
                    if graph2==7%%Señal de impulso
                        sign2=zeros(length(t));
                        sign2(des2-t1*fs)=A2;
                    end
                    if graph2==8%%Señal rampa
                        sign2=A2.*(t+des2);
                    end
                    if graph2==9%%Señal rectangular
                        sign2=A2.*rectpuls(t+des2,1);
                    end
                    if graph2==10%%Señal triangular
                        sign2=A2.*tripuls(t+des2,1);
                    end
                end
                if app.Funcion2Switch.Value==1
                    sign2=A2.*app.Funcion;
                end
                %%Con las funciones ya tomadas solo hace falta ver si
                %%se suman o se multiplican
                if operacion==2
                signal=sign1+sign2;
                text_title='Suma de señales';
                end
                if operacion==3
                    signal=sign1.*sign2;
                    text_title='Multiplicacion de señales';
                end
                

            end%%End suma y multiplicacion de funciones
%%====================================================================================
            if operacion==4%%Diferenciación 
                t=t1:1/fs:T-1/fs;
                if app.Funcion1Switch.Value=='1'%Señal anterior
                    signal=diff(app.Funcion);
                    signal=[signal,signal(end)];
                    graph=0;
                    text_title='Derivada de funcion anterior';
                end
                  

                if graph==1
                    signal=diff(A.*square(2*pi*f*t+des,duty));
                    signal=[signal,signal(end)];%La derivada hace que se reduzca la longitud del vector, de esta forma la dejamos igual
                    %fprintf(" Cuadrada %d y t %d",length(signal),length(t))
                    text_title='Derivada de la señal cuadrada';%Solo aplica en este tipo de funciones
                end
                if graph==2
                        signal=diff(A.*sawtooth(2*pi*t+des,width));
                        signal=[signal,signal(end)];
                    text_title='Derivada de la señal triangular';
                end
                if graph==3
                    signal=A.*2*pi.*cos(2*pi*t+des);%%La derivada de la funcion seno
                    text_title='Derivada de la funcion seno';
                end
                if graph==4%%Exponencial
                    if app.ExponenteSwitch.Value==0%Negativa
                        signal=A.*B.^(t).*log(B);%Funciones derivadas
                    end
                    if app.ExponenteSwitch.Value==1
                        signal=-A.*B.^(-t).*log(B);
                    end
                    text_title='Derivada de la funcion exponencial';
                end
                if graph==5%Exponencial Compleja
                    x=1i.*f.*t.*exp(1i*(f*t+pi/4));% derivada e^u *du
                    if app.Compleja1Switch.Value=='0'%Compleja
                        signal=imag(x);
                    end
                    if app.Compleja1Switch.Value=='1'%Real
                        signal=real(x);
                    end
                    text_title='Derivada de la funcion exponencial compleja';
                end
                if graph==6%escalon
                    sign2=A.*ones(1,length(t));
                    if des>t(end)
                        des=0;
                    end
                   for i=1:des+1
                       sign2(i)=0;
                   end
                   signal=diff(sign2);
                   signal=[signal,signal(end)];
                   text_title='Derivada de la señal escalon';
                end
                if graph==7%impulso
                    sign1=zeros(length(t));
                    sign1(des)=A;
                    signal=diff(sign1);
                    signal=[signal,signal(end)];
                    text_title='Derivada de la señal de impulso';
                end
                if graph==8%Rampa
                        signal=ones(1,length(t)).*A;
                        text_title='Derivada de la señal rampa';
                end
                if graph==9%rectangular
                    sign1=A.*rectpuls(t+des,1);
                    signal=diff(sign1);
                    signal=[signal,signal(end)];
                    text_title='Derivada de la señal rectangular';
                end
                if graph==10%triangular
                    sign1=A.*tripuls(t+des,1);
                    signal=diff(sign1);
                    signal=[signal,signal(end)];
                    text_title='Derivada de la señal triangular';
                end
            end%%end Diferenciación

%%====================================================================================
            if operacion==5%%integracion
            
                t=t1:1/fs:T-1/fs;
                if app.Funcion2Switch.Value==1
                    signal=cumtrapz(app.t,app.Funcion);
                    text_title='Integral de señal anterior';
                    graph=0;
                end

                if graph==1%Cuadrada
                    signal=cumtrapz(t,A.*square(2*pi*f*t+des,duty));
                    text_title='Integral de señal cuadrada';
                end
                if graph==2%Diente de sierra
                    signal=cumtrapz(t,(A.*sawtooth(2*pi*t+des,width)));
                    text_title='Integral de señal diente de sierra';
                    
                end
                if graph==3%Senoidal
                    signal=-(A/2*pi*f)*cos(2*pi*f*t+des);
                    text_title='Integral de la función seno';
                end
                if graph==4
                    if app.ExponenteSwitch.Value==0
                        signal=A.*(B.^-(t+des))/(log(B));
                    end
                    if app.ExponenteSwitch.Value==1
                        signal=A.*(B.^(t+des))/(log(B));
                    end

                    text_title='Integral de la función exponencial';
                end
                if graph==5%Exponencial compleja
                     x=A.*exp(1i.*(f.*t+pi/4));
                     signal=zeros(size(t));
     
                    if app.Compleja1Switch.Value=='0' %0 de imaginaria
                        signal_aux=imag(x);
                        signal=cumtrapz(t,signal_aux);
                    end
                    if app.Compleja1Switch.Value=='1' %1 de real
                        signal_aux=real(x);
                        signal=cumtrapz(t,signal_aux);
                        
                    end
  
                    text_title='Señal exponencial compleja';
                end
                if graph==6%%Señal escalon
                    signal=A.*ones(1,length(t));
                    if des>t(end)
                        des=0;
                    end
                   for i=1:des+1
                       signal(i)=0;
                   end

                    text_title='Integral de señal escalon';
                end
                if graph==7%impulso
                    signal=zeros(size(t));
                    for i=des:length(t)
                        signal(i)=1;
                    end
                    text_title='Integral de funcion de impulso';
                end
                if graph==8%Rampa
                    signal=(A/2).*((t^2)+t);
                    text_title='Integral de señal rampa';
                end
                if graph==9
                    signal=cumtrapz(t,A.*rectpuls(t+des,1));
                    text_title='Integral de señal rectangular';
                end
                if graph==10
                    signal=cumtrapz(t,A.*tripuls(t+des,1));
                    text_title='Integral de señal triangular';
                end
            end%----------------end integracion
            %%=========================================================================

            if operacion == 6%Operaciones de tiempo
                text_title='Operacion sobre variable independiente';
                t=t1:1/fs:T-1/fs;
               factor_t = app.factor_escalamiento;
               reflexion = app.reflex;
               corrimientos = app.corrimiento;
               t_des_aux = t - corrimientos;
               %fprintf('Factor = %d',factor_t)
               t_des = factor_t*t_des_aux;%%<----------------------------------------------
               
               % Call dialog box with input values

                    if graph==1
                        signal=A.*square(2*pi*f* t_des,duty);
                    end
                    if graph==2
                        signal=A.*sawtooth(2*pi* t_des,width);
                    end
                    if graph==3
                        signal=A.*sin(2*pi*f*t_des);
                    end
                    if graph==4
                        if app.ExponenteSwitch.Value==0 %%Switch del exponente
                            signal=A.*B.^(-t_des);
                        end
                        if app.ExponenteSwitch.Value==1
                            signal=A.*B.^(t_des);
                        end
                    end
                    if graph==5
                        x=A.*exp(1i.*(f.*t_des+pi/4));
                        if app.Compleja1Switch.Value=='0'%compleja
                            signal=imag(x);
                        end
                       if app.Compleja1Switch.Value=='1'
                            signal=real(x);       
                       end
    
                    end
                    if graph==6%%Señal de escalon ya esta
                        des=corrimientos*fs+1;
                         signal=A.*ones(1,length(t_des));
                       for i=1:(1/factor_t)*(des)+1-t1*fs
                           signal(i)=0;
                       end
                        %%Componer------------------------->
                    end
                    if graph==7%%Señal de impulso
                        des=corrimientos*fs+1;
                        signal=zeros(length(t_des));
                        signal(des-t1*fs)=A;
                    end

                    if graph==8%%Señal rampa
                        signal=A.*(t_des);
                        %signal=interp1(t,signalx,t_des);

                        
                    end

                    if graph==9%%Señal rectangular
                        signal=A.*rectpuls(t_des,1);
                    end

                    if graph==10%Señal triangular
                         signal=A.*tripuls(t_des,1);
    
                    end
                    if reflexion == 1
                   signal = -signal;
                    end
            end
            
        
             app.Funcion=signal;
            app.intervalo=t;
            xlabel(app.UIAxes,"Tiempo (sec)")
            ylabel(app.UIAxes,"Amplitud")
            title(app.UIAxes,text_title)
            xlabel(app.UIAxes3,"Tiempo (sec)")
            ylabel(app.UIAxes3,"Amplitud")
            xlabel(app.UIAxes4,"Tiempo (sec)")
            ylabel(app.UIAxes4,"Amplitud")
            xlabel(app.UIAxes5,"Tiempo (sec)")
            ylabel(app.UIAxes5,"Amplitud")
    
            title(app.UIAxes5,"Correlacion de MATLAB");
   
            if app.NaturalezaSwitch.Value==0%Señal continua


                grid(app.UIAxes,"on")


                grid(app.UIAxes5,"on");
            end
            if app.NaturalezaSwitch.Value==1
                stem(app.UIAxes,t_conv,signal)
                grid(app.UIAxes,"on")


                stem(app.UIAxes5,t_conv,signalauto);
                grid(app.UIAxes5,"on");
                
            
            end


        end

        % Value changed function: OperacionDropDown
        function OperacionDropDownValueChanged(app, event)
            value = app.OperacionDropDown.Value;
             %%Cambiar que opciones son visibles dentro de las operaciones
            
            if value==1 %%Para agrandar la señal solo necesitamos el escalamiento, amplitud, periodo y desfase
                app.EscalamientoEditField.Visible="on";
                app.EscalamientoEditFieldLabel.Visible="on";

                app.Grafica2DropDown.Visible="off";
                app.Grafica2DropDownLabel.Visible="off";

                app.ExponenteSwitch.Visible="off";
                app.ExponenteSwitchLabel.Visible="off";
    
                app.Exponente2Switch.Visible="off";
                app.Exponente2SwitchLabel.Visible="off";
    
                app.Compleja1Switch.Visible="off";
                app.Compleja1SwitchLabel.Visible="off";
    
                app.Compleja2Switch.Visible="off";
                app.Compleja2SwitchLabel.Visible="off";
                
                app.Amplitud1EditField.Visible="on";
                app.Amplitud1EditFieldLabel.Visible="on";

                app.PeriodosEditField.Visible="on";
                app.PeriodosEditFieldLabel.Visible="on";

                app.Desfase1EditField.Visible="on";
                app.Desfase1EditFieldLabel.Visible="on";

                app.Ciclo1EditField.Visible="off";%%Duty cycle
                app.Ciclo1EditFieldLabel.Visible="off";

                app.Amplitud2EditField.Visible="off";
                app.Amplitud2EditFieldLabel.Visible="off";

                app.Desfase2EditField.Visible="off";
                app.Desfase2EditFieldLabel.Visible="off";

                app.Ciclo2EditField.Visible="off";
                app.Ciclo2EditFieldLabel.Visible="off";

                app.Funcion1Switch.Visible="on";
                app.Funcion1SwitchLabel.Visible="on";

                app.Funcion2Switch.Visible="off";
                app.Funcion2Switch.Visible="off";

                app.F02EditField.Visible="off";
                app.F02EditFieldLabel.Visible="off";

            end
            if value==2%%Suma de funciones aqui necesitamos los parametros de la funcion 2
                app.EscalamientoEditField.Visible="off";
                app.EscalamientoEditFieldLabel.Visible="off";

                app.Grafica2DropDown.Visible="on";
                app.Grafica2DropDownLabel.Visible="on";

                app.ExponenteSwitch.Visible="off";
                app.ExponenteSwitchLabel.Visible="off";
    
                app.Exponente2Switch.Visible="off";
                app.Exponente2SwitchLabel.Visible="off";
    
                app.Compleja1Switch.Visible="off";
                app.Compleja1SwitchLabel.Visible="off";
    
                app.Compleja2Switch.Visible="off";
                app.Compleja2SwitchLabel.Visible="off";

                app.Amplitud1EditField.Visible="off";
                app.Amplitud1EditFieldLabel.Visible="off";

                app.Desfase1EditField.Visible="on";
                app.Desfase1EditFieldLabel.Visible="on";

                app.Ciclo1EditField.Visible="off";%%Duty cycle
                app.Ciclo1EditFieldLabel.Visible="off";

                app.Amplitud2EditField.Visible="off";
                app.Amplitud1EditFieldLabel.Visible="off";

                app.Desfase2EditField.Visible="on";
                app.Desfase2EditFieldLabel.Visible="on";

                app.Ciclo2EditField.Visible="off";
                app.Ciclo2EditFieldLabel.Visible="off";

                app.Funcion1Switch.Visible="on";
                app.Funcion1SwitchLabel.Visible="on";

                app.Funcion2Switch.Visible="on";
                app.Funcion2Switch.Visible="on";

                app.F02EditField.Visible="on";
                app.F02EditFieldLabel.Visible="on";

            end
            if value==3%%Multiplicacion de funciones
                app.EscalamientoEditField.Visible="off";
                app.EscalamientoEditFieldLabel.Visible="off";

                app.Grafica2DropDown.Visible="on";
                app.Grafica2DropDownLabel.Visible="on";

                app.ExponenteSwitch.Visible="off";
                app.ExponenteSwitchLabel.Visible="off";
    
                app.Exponente2Switch.Visible="off";
                app.Exponente2SwitchLabel.Visible="off";
    
                app.Compleja1Switch.Visible="off";
                app.Compleja1SwitchLabel.Visible="off";
    
                app.Compleja2Switch.Visible="off";
                app.Compleja2SwitchLabel.Visible="off";

                app.Amplitud1EditField.Visible="on";
                app.Amplitud1EditFieldLabel.Visible="on";

                app.Desfase1EditField.Visible="on";
                app.Desfase1EditFieldLabel.Visible="on";

                app.Ciclo1EditField.Visible="off";%%Duty cycle
                app.Ciclo1EditFieldLabel.Visible="off";

                app.Amplitud2EditField.Visible="off";
                app.Amplitud1EditFieldLabel.Visible="off";

                app.Desfase2EditField.Visible="off";
                app.Desfase2EditFieldLabel.Visible="off";

                app.Ciclo2EditField.Visible="off";
                app.Ciclo2EditFieldLabel.Visible="off";

                app.Funcion1Switch.Visible="on";
                app.Funcion1SwitchLabel.Visible="on";

                app.Funcion2Switch.Visible="on";
                app.Funcion2Switch.Visible="on";

                app.F02EditField.Visible="on";
                app.F02EditFieldLabel.Visible="on";

            end
            if value==4%%Diferenciacion
                app.EscalamientoEditField.Visible="off";
                app.EscalamientoEditFieldLabel.Visible="on";

                app.Grafica2DropDown.Visible="off";
                app.Grafica2DropDownLabel.Visible="off";

                app.ExponenteSwitch.Visible="off";
                app.ExponenteSwitchLabel.Visible="off";
    
                app.Exponente2Switch.Visible="off";
                app.Exponente2SwitchLabel.Visible="off";
    
                app.Compleja1Switch.Visible="off";
                app.Compleja1SwitchLabel.Visible="off";
    
                app.Compleja2Switch.Visible="off";
                app.Compleja2SwitchLabel.Visible="off";

                app.Amplitud1EditField.Visible="on";
                app.Amplitud1EditFieldLabel.Visible="on";

                app.Desfase1EditField.Visible="on";
                app.Desfase1EditFieldLabel.Visible="on";

                app.Ciclo1EditField.Visible="off";%%Duty cycle
                app.Ciclo1EditFieldLabel.Visible="off";

                app.Amplitud2EditField.Visible="off";
                app.Amplitud1EditFieldLabel.Visible="off";

                app.Desfase2EditField.Visible="off";
                app.Desfase2EditFieldLabel.Visible="off";

                app.Ciclo2EditField.Visible="off";
                app.Ciclo2EditFieldLabel.Visible="off";

                app.Funcion1Switch.Visible="on";
                app.Funcion1SwitchLabel.Visible="on";

                app.Funcion2Switch.Visible="off";
                app.Funcion2Switch.Visible="off";

                app.F02EditField.Visible="off";
                app.F02EditFieldLabel.Visible="off";
            end
            if value==5%%Integracion
                app.EscalamientoEditField.Visible="off";
                app.EscalamientoEditFieldLabel.Visible="on";

                app.Grafica2DropDown.Visible="off";
                app.Grafica2DropDownLabel.Visible="off";

                app.ExponenteSwitch.Visible="off";
                app.ExponenteSwitchLabel.Visible="off";
    
                app.Exponente2Switch.Visible="off";
                app.Exponente2SwitchLabel.Visible="off";
    
                app.Compleja1Switch.Visible="off";
                app.Compleja1SwitchLabel.Visible="off";
    
                app.Compleja2Switch.Visible="off";
                app.Compleja2SwitchLabel.Visible="off";

                app.Amplitud1EditField.Visible="on";
                app.Amplitud1EditFieldLabel.Visible="on";

                app.Desfase1EditField.Visible="on";
                app.Desfase1EditFieldLabel.Visible="on";

                app.Ciclo1EditField.Visible="off";%%Duty cycle
                app.Ciclo1EditFieldLabel.Visible="off";

                app.Amplitud2EditField.Visible="off";
                app.Amplitud1EditFieldLabel.Visible="off";

                app.Desfase2EditField.Visible="off";
                app.Desfase2EditFieldLabel.Visible="off";

                app.Ciclo2EditField.Visible="off";
                app.Ciclo2EditFieldLabel.Visible="off";

                app.Funcion1Switch.Visible="on";
                app.Funcion1SwitchLabel.Visible="on";

                app.Funcion2Switch.Visible="off";
                app.Funcion2Switch.Visible="off";

                app.F02EditField.Visible="off";
                app.F02EditFieldLabel.Visible="off";
            end
            if value == 6 %Operaciones de tiempo 
                app.Ciclo2EditField.Visible="off";
                app.Ciclo2EditFieldLabel.Visible="off";
                app.Amplitud2EditField.Visible="off";
                app.Amplitud2EditFieldLabel.Visible="off";
                app.F02EditField.Visible="off";
                app.F02EditFieldLabel.Visible="off";
                app.Ancho2EditField.Visible="off";
                app.Ancho2EditFieldLabel.Visible="off";
    
                app.Desfase2EditField.Visible="off";
                app.Desfase2EditFieldLabel.Visible="off";
    
                app.Base2EditField.Visible="off";
                app.Base2EditFieldLabel.Visible="off";
    
                app.Funcion1Switch.Visible="on";
                app.Funcion1SwitchLabel.Visible="on";
    
                app.Funcion2Switch.Visible="off";
                app.Funcion2SwitchLabel.Visible="off";
            end
               

        end

        % Value changed function: Grafica1DropDown
        function Grafica1DropDownValueChanged(app, event)
            %%Cambios de valor en el dropdown de la primera grafica
            value = app.Grafica1DropDown.Value;
            if value==1 %%Si es grafica Cuadrada
                app.ExponenteSwitch.Visible="off";
                app.ExponenteSwitchLabel.Visible="off";

                app.Compleja1Switch.Visible="off";
                app.Compleja1SwitchLabel.Visible="off";

                app.Amplitud1EditField.Visible="on";
                app.Amplitud1EditFieldLabel.Visible="on";

                app.Ciclo1EditField.Visible="on";
                app.Ciclo1EditFieldLabel.Visible="on";

                app.Ancho1EditField.Visible="off";
                app.Ancho1EditFieldLabel.Visible="off";

                app.Base1EditField.Visible="off";
                app.Base1EditFieldLabel.Visible="off";

                app.Desfase1EditField.Visible="on";
                app.Desfase1EditFieldLabel.Visible="on";


            end

            if value==2%%Si es grafica diente de sierra
                app.ExponenteSwitch.Visible="off";
                app.ExponenteSwitchLabel.Visible="off";

                app.Compleja1Switch.Visible="off";
                app.Compleja1SwitchLabel.Visible="off";

                app.Amplitud1EditField.Visible="on";
                app.Amplitud1EditFieldLabel.Visible="on";

                app.Ciclo1EditField.Visible="off";
                app.Ciclo1EditFieldLabel.Visible="off";

                app.Ancho1EditField.Visible="on";
                app.Ancho1EditFieldLabel.Visible="on";

                app.Base1EditField.Visible="off";
                app.Base1EditFieldLabel.Visible="off";

                app.Desfase1EditField.Visible="on";
                app.Desfase1EditField.Visible="on";
            end

            if value==3%%Si es grafica Senoidal
                app.ExponenteSwitch.Visible="off";
                app.ExponenteSwitchLabel.Visible="off";

                app.Compleja1Switch.Visible="off";
                app.Compleja1SwitchLabel.Visible="off";

                app.Amplitud1EditField.Visible="on";
                app.Amplitud1EditFieldLabel.Visible="on";

                app.Ciclo1EditField.Visible="off";
                app.Ciclo1EditFieldLabel.Visible="off";

                app.Ancho1EditField.Visible="off";
                app.Ancho1EditFieldLabel.Visible="off";

                app.Base1EditField.Visible="off";
                app.Base1EditFieldLabel.Visible="off";

                app.Desfase1EditField.Visible="on";
                app.Desfase1EditField.Visible="on";
            end

            if value==4%%Si es grafica exponencial
                app.ExponenteSwitch.Visible="on";
                app.ExponenteSwitchLabel.Visible="on";

                app.Compleja1Switch.Visible="off";
                app.Compleja1SwitchLabel.Visible="off";

                app.Amplitud1EditField.Visible="on";
                app.Amplitud1EditFieldLabel.Visible="on";

                app.Ciclo1EditField.Visible="off";
                app.Ciclo1EditFieldLabel.Visible="off";

                app.Ancho1EditField.Visible="off";
                app.Ancho1EditFieldLabel.Visible="off";

                app.Base1EditField.Visible="on";
                app.Base1EditFieldLabel.Visible="on";

                app.Desfase1EditField.Visible="off";
                app.Desfase1EditFieldLabel.Visible="off";
            end

            if value==5%%Si es grafica exponencial compleja
                app.ExponenteSwitch.Visible="off";
                app.ExponenteSwitchLabel.Visible="off";

                app.Compleja1Switch.Visible="on";
                app.Compleja1SwitchLabel.Visible="on";

                app.Amplitud1EditField.Visible="on";
                app.Amplitud1EditFieldLabel.Visible="on";

                app.Ciclo1EditField.Visible="off";
                app.Ciclo1EditFieldLabel.Visible="off";

                app.Ancho1EditField.Visible="off";
                app.Ancho1EditFieldLabel.Visible="off";

                app.Base1EditField.Visible="off";
                app.Base1EditFieldLabel.Visible="off";

                app.Desfase1EditField.Visible="off";
                app.Desfase1EditFieldLabel.Visible="off";

            end
            if value==6%%Escalon
                app.ExponenteSwitch.Visible="off";
                app.ExponenteSwitchLabel.Visible="off";

                app.Compleja1Switch.Visible="off";
                app.Compleja1SwitchLabel.Visible="off";

                app.Amplitud1EditField.Visible="on";
                app.Amplitud1EditFieldLabel.Visible="on";

                app.Ciclo1EditField.Visible="off";
                app.Ciclo1EditFieldLabel.Visible="off";

                app.Ancho1EditField.Visible="off";
                app.Ancho1EditFieldLabel.Visible="off";

                app.Base1EditField.Visible="off";
                app.Base1EditFieldLabel.Visible="off";

                app.Desfase1EditField.Visible="on";
                app.Desfase1EditFieldLabel.Visible="on";
            end
            if value==7%%Impulso
                app.ExponenteSwitch.Visible="off";
                app.ExponenteSwitchLabel.Visible="off";

                app.Compleja1Switch.Visible="off";
                app.Compleja1SwitchLabel.Visible="off";

                app.Amplitud1EditField.Visible="on";
                app.Amplitud1EditFieldLabel.Visible="on";

                app.Ciclo1EditField.Visible="off";
                app.Ciclo1EditFieldLabel.Visible="off";

                app.Ancho1EditField.Visible="off";
                app.Ancho1EditFieldLabel.Visible="off";

                app.Base1EditField.Visible="off";
                app.Base1EditFieldLabel.Visible="off";

                app.Desfase1EditField.Visible="on";
                app.Desfase1EditFieldLabel.Visible="on";
            end
            if value==8%%Rampa
                app.ExponenteSwitch.Visible="off";
                app.ExponenteSwitchLabel.Visible="off";

                app.Compleja1Switch.Visible="off";
                app.Compleja1SwitchLabel.Visible="off";

                app.Amplitud1EditField.Visible="on";
                app.Amplitud1EditFieldLabel.Visible="on";

                app.Ciclo1EditField.Visible="off";
                app.Ciclo1EditFieldLabel.Visible="off";

                app.Ancho1EditField.Visible="off";
                app.Ancho1EditFieldLabel.Visible="off";

                app.Base1EditField.Visible="off";
                app.Base1EditFieldLabel.Visible="off";

                app.Desfase1EditField.Visible="on";
                app.Desfase1EditFieldLabel.Visible="on";
            end
            if value==9%%Rectangular
                app.ExponenteSwitch.Visible="off";
                app.ExponenteSwitchLabel.Visible="off";

                app.Compleja1Switch.Visible="off";
                app.Compleja1SwitchLabel.Visible="off";

                app.Amplitud1EditField.Visible="on";
                app.Amplitud1EditFieldLabel.Visible="on";

                app.Ciclo1EditField.Visible="off";
                app.Ciclo1EditFieldLabel.Visible="off";

                app.Ancho1EditField.Visible="off";
                app.Ancho1EditFieldLabel.Visible="off";

                app.Base1EditField.Visible="off";
                app.Base1EditFieldLabel.Visible="off";

                app.Desfase1EditField.Visible="on";
                app.Desfase1EditFieldLabel.Visible="on";
            end
            if value==10%%Triangular
                app.ExponenteSwitch.Visible="off";
                app.ExponenteSwitchLabel.Visible="off";

                app.Compleja1Switch.Visible="off";
                app.Compleja1SwitchLabel.Visible="off";

                app.Amplitud1EditField.Visible="on";
                app.Amplitud1EditFieldLabel.Visible="on";

                app.Ciclo1EditField.Visible="off";
                app.Ciclo1EditFieldLabel.Visible="off";

                app.Ancho1EditField.Visible="off";
                app.Ancho1EditFieldLabel.Visible="off";

                app.Base1EditField.Visible="off";
                app.Base1EditFieldLabel.Visible="off";

                app.Desfase1EditField.Visible="on";
                app.Desfase1EditFieldLabel.Visible="on";
            end


        end

        % Value changed function: Grafica2DropDown
        function Grafica2DropDownValueChanged2(app, event)
            value = app.Grafica2DropDown.Value;
            if value==1%%Cuadrada
                app.Exponente2Switch.Visible="off";
                app.Exponente2SwitchLabel.Visible="off";

                app.Compleja2Switch.Visible="off";
                app.Compleja2SwitchLabel.Visible="off";

                app.Amplitud2EditField.Visible="on";
                app.Amplitud2EditFieldLabel.Visible="on";

                app.Ciclo2EditField.Visible="on";
                app.Ciclo2EditFieldLabel.Visible="on";

                app.Ancho2EditField.Visible="off";
                app.Ancho2EditFieldLabel.Visible="off";

                app.Base2EditField.Visible="off";
                app.Base2EditFieldLabel.Visible="off";

                app.Desfase2EditField.Visible="on";
                app.Desfase2EditFieldLabel.Visible="on";
            end
            if value==2%%Diente de sierra
                app.Exponente2Switch.Visible="off";
                app.Exponente2SwitchLabel.Visible="off";

                app.Compleja2Switch.Visible="off";
                app.Compleja2SwitchLabel.Visible="off";

                app.Amplitud2EditField.Visible="on";
                app.Amplitud2EditFieldLabel.Visible="on";

                app.Ciclo2EditField.Visible="off";
                app.Ciclo2EditFieldLabel.Visible="off";

                app.Ancho2EditField.Visible="on";
                app.Ancho2EditFieldLabel.Visible="on";

                app.Base2EditField.Visible="off";
                app.Base2EditFieldLabel.Visible="off";

                app.Desfase2EditField.Visible="on";
                app.Desfase2EditFieldLabel.Visible="on";
            end
            if value==3%%Senoidal
                app.Exponente2Switch.Visible="off";
                app.Exponente2SwitchLabel.Visible="off";

                app.Compleja2Switch.Visible="off";
                app.Compleja2SwitchLabel.Visible="off";

                app.Amplitud2EditField.Visible="on";
                app.Amplitud2EditFieldLabel.Visible="on";

                app.Ciclo2EditField.Visible="off";
                app.Ciclo2EditFieldLabel.Visible="off";

                app.Ancho2EditField.Visible="off";
                app.Ancho2EditFieldLabel.Visible="off";

                app.Base2EditField.Visible="off";
                app.Base2EditFieldLabel.Visible="off";

                app.Desfase2EditField.Visible="on";
                app.Desfase2EditFieldLabel.Visible="on";
            end
            if value==4%%Exponencial
                app.Exponente2Switch.Visible="on";
                app.Exponente2SwitchLabel.Visible="on";

                app.Compleja2Switch.Visible="off";
                app.Compleja2SwitchLabel.Visible="off";

                app.Amplitud2EditField.Visible="on";
                app.Amplitud2EditFieldLabel.Visible="on";

                app.Ciclo2EditField.Visible="off";
                app.Ciclo2EditFieldLabel.Visible="off";

                app.Ancho2EditField.Visible="off";
                app.Ancho2EditFieldLabel.Visible="off";

                app.Base2EditField.Visible="on";
                app.Base2EditFieldLabel.Visible="on";

                app.Desfase2EditField.Visible="off";
                app.Desfase2EditFieldLabel.Visible="off";
            end
            if value==5%%Exponencial compleja
                app.Exponente2Switch.Visible="off";
                app.Exponente2SwitchLabel.Visible="off";

                app.Compleja2Switch.Visible="on";
                app.Compleja2SwitchLabel.Visible="on";

                app.Amplitud2EditField.Visible="on";
                app.Amplitud2EditFieldLabel.Visible="on";

                app.Ciclo2EditField.Visible="off";
                app.Ciclo2EditFieldLabel.Visible="off";

                app.Ancho2EditField.Visible="off";
                app.Ancho2EditFieldLabel.Visible="off";

                app.Base2EditField.Visible="off";
                app.Base2EditFieldLabel.Visible="off";

                app.Desfase2EditField.Visible="on";
                app.Desfase2EditFieldLabel.Visible="on";
            end
            if value==6%%Escalon
                app.Exponente2Switch.Visible="off";
                app.Exponente2SwitchLabel.Visible="off";

                app.Compleja2Switch.Visible="off";
                app.Compleja2SwitchLabel.Visible="off";

                app.Amplitud2EditField.Visible="on";
                app.Amplitud2EditFieldLabel.Visible="on";

                app.Ciclo2EditField.Visible="off";
                app.Ciclo2EditFieldLabel.Visible="off";

                app.Ancho2EditField.Visible="off";
                app.Ancho2EditFieldLabel.Visible="off";

                app.Base2EditField.Visible="off";
                app.Base2EditFieldLabel.Visible="off";

                app.Desfase2EditField.Visible="on";
                app.Desfase2EditFieldLabel.Visible="on";
            end
            if value==7%Impulso
                app.Exponente2Switch.Visible="off";
                app.Exponente2SwitchLabel.Visible="off";

                app.Compleja2Switch.Visible="off";
                app.Compleja2SwitchLabel.Visible="off";

                app.Amplitud2EditField.Visible="on";
                app.Amplitud2EditFieldLabel.Visible="on";

                app.Ciclo2EditField.Visible="off";
                app.Ciclo2EditFieldLabel.Visible="off";

                app.Ancho2EditField.Visible="off";
                app.Ancho2EditFieldLabel.Visible="off";

                app.Base2EditField.Visible="off";
                app.Base2EditFieldLabel.Visible="off";

                app.Desfase2EditField.Visible="on";
                app.Desfase2EditFieldLabel.Visible="on";
            end
            if value==8%rampa
                app.Exponente2Switch.Visible="off";
                app.Exponente2SwitchLabel.Visible="off";

                app.Compleja2Switch.Visible="off";
                app.Compleja2SwitchLabel.Visible="off";

                app.Amplitud2EditField.Visible="on";
                app.Amplitud2EditFieldLabel.Visible="on";

                app.Ciclo2EditField.Visible="off";
                app.Ciclo2EditFieldLabel.Visible="off";

                app.Ancho2EditField.Visible="off";
                app.Ancho2EditFieldLabel.Visible="off";

                app.Base2EditField.Visible="off";
                app.Base2EditFieldLabel.Visible="off";

                app.Desfase2EditField.Visible="on";
                app.Desfase2EditFieldLabel.Visible="on";
            end
            if value==9%Rectangular
                app.Exponente2Switch.Visible="off";
                app.Exponente2SwitchLabel.Visible="off";

                app.Amplitud2EditField.Visible="on";
                app.Amplitud2EditFieldLabel.Visible="on";

                app.Ciclo2EditField.Visible="off";
                app.Ciclo2EditFieldLabel.Visible="off";

                app.Ancho2EditField.Visible="off";
                app.Ancho2EditFieldLabel.Visible="off";

                app.Base2EditField.Visible="off";
                app.Base2EditFieldLabel.Visible="off";

                app.Desfase2EditField.Visible="on";
                
                app.Desfase2EditFieldLabel.Visible="on";

                app.Compleja2Switch.Visible="off";
                app.Compleja2SwitchLabel.Visible="off";
            end
            if value==10%triangular
                app.Exponente2Switch.Visible="off";
                app.Exponente2SwitchLabel.Visible="off";

                app.Compleja2Switch.Visible="off";
                app.Compleja2SwitchLabel.Visible="off";

                app.Amplitud2EditField.Visible="on";
                app.Amplitud2EditFieldLabel.Visible="on";

                app.Ciclo2EditField.Visible="off";
                app.Ciclo2EditFieldLabel.Visible="off";

                app.Ancho2EditField.Visible="off";
                app.Ancho2EditFieldLabel.Visible="off";

                app.Base2EditField.Visible="off";
                app.Base2EditFieldLabel.Visible="off";

                app.Desfase2EditField.Visible="on";
                app.Desfase2EditFieldLabel.Visible="on";
            end
            
        end

        % Value changed function: Funcion1Switch
        function Funcion1SwitchValueChanged(app, event)
            value = app.Funcion1Switch.Value;
             if value=='0'
                app.Grafica1DropDown.Visible="on";
                app.Grafica1DropDownLabel.Visible="on";
            end


            if value=='1'
                app.Grafica1DropDown.Visible="off";
                app.Grafica1DropDownLabel.Visible="off";

                 app.EscalamientoEditField.Visible="off";
                app.EscalamientoEditFieldLabel.Visible="off";

                app.ExponenteSwitch.Visible="off";
                app.ExponenteSwitchLabel.Visible="off";

                app.Compleja1Switch.Visible="off";
                app.Compleja1SwitchLabel.Visible="off";

                app.Amplitud1EditField.Visible="on";
                app.Amplitud1EditFieldLabel.Visible="on";

                app.Ciclo1EditField.Visible="off";
                app.Ciclo1EditFieldLabel.Visible="off";

                app.ExponenteSwitch.Visible="off";
                app.ExponenteSwitchLabel.Visible="off";

                app.Ancho1EditField.Visible="off";
                app.Ancho1EditFieldLabel.Visible="off";

                app.Base1EditField.Visible="off";
                app.Base1EditFieldLabel.Visible="off";


            end
        end

        % Value changed function: Funcion2Switch
        function Funcion2SwitchValueChanged(app, event)
            value = app.Funcion2Switch.Value;
            if value==0
                app.Grafica2DropDown.Visible="on";
                app.Grafica2DropDownLabel.Visible="on";
            end
            if value==1
                app.Grafica2DropDown.Visible="off";
                app.Grafica2DropDownLabel.Visible="off";


                app.Exponente2Switch.Visible="off";
                app.Exponente2SwitchLabel.Visible="off";

                app.Compleja2Switch.Visible="off";
                app.Compleja2SwitchLabel.Visible="off";

                app.Amplitud2EditField.Visible="on";
                app.Amplitud2EditFieldLabel.Visible="on";

                app.Ciclo2EditField.Visible="off";
                app.Ciclo2EditFieldLabel.Visible="off";

                app.Exponente2Switch.Visible="off";
                app.Exponente2SwitchLabel.Visible="off";

                app.Ancho2EditField.Visible="off";
                app.Ancho2EditFieldLabel.Visible="off";

                app.Base2EditField.Visible="off";
                app.Base2EditFieldLabel.Visible="off";
                app.F02EditField.Visible="off";
                app.F02EditFieldLabel.Visible="off";
            end
            
        end

        % Button pushed function: VarIndButton
        function VarIndButtonPushed(app, event)
         app.DialogApp = VarInd(app);
        end

        % Value changed function: Desfase1EditField
        function Desfase1EditFieldValueChanged(app, event)
            value = app.Desfase1EditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.Button = uibutton(app.UIFigure, 'push');
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GeneradordesealesTab = uitab(app.TabGroup);
            app.GeneradordesealesTab.Title = 'Generador de señales';
            app.UIAxes = uiaxes(app.GeneradordesealesTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.GraficarButton = uibutton(app.GeneradordesealesTab, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.EscalamientoEditFieldLabel = uilabel(app.GeneradordesealesTab);
            app.EscalamientoEditFieldLabel.Text = 'Escalamiento';
            app.EscalamientoEditField = uieditfield(app.GeneradordesealesTab, 'numeric');
            app.OperacionDropDownLabel = uilabel(app.GeneradordesealesTab);
            app.OperacionDropDownLabel.Text = 'Operacion';
            app.OperacionDropDown = uidropdown(app.GeneradordesealesTab);
            app.OperacionDropDown.Items = {'Seleccionar', 'Escalamiento', 'Suma', 'Multiplicacion', 'Diferenciacion', 'Integracion', 'Var Ind'};
            app.OperacionDropDown.ItemsData = [0 1 2 3 4 5 6 7 8 9];
            app.OperacionDropDown.ValueChangedFcn = createCallbackFcn(app, @OperacionDropDownValueChanged, true);
            app.OperacionDropDown.Value = 0;
            app.Grafica1DropDownLabel = uilabel(app.GeneradordesealesTab);
            app.Grafica1DropDownLabel.Text = 'Grafica 1';
            app.Grafica1DropDown = uidropdown(app.GeneradordesealesTab);
            app.Grafica1DropDown.Items = {'Selecciona', 'Cuadrada', 'Diente de sierra', 'Senoidal', 'Exponencial', 'E. Compleja', 'Escalon', 'Impulso', 'Rampa', 'Rectangular', 'Triangular'};
            app.Grafica1DropDown.ItemsData = [0 1 2 3 4 5 6 7 8 9 10];
            app.Grafica1DropDown.ValueChangedFcn = createCallbackFcn(app, @Grafica1DropDownValueChanged, true);
            app.Grafica1DropDown.Value = 0;
            app.ExponenteSwitchLabel = uilabel(app.GeneradordesealesTab);
            app.ExponenteSwitchLabel.Text = 'Exponente';
            app.ExponenteSwitch = uiswitch(app.GeneradordesealesTab, 'slider');
            app.ExponenteSwitch.Items = {'-', '+'};
            app.ExponenteSwitch.ItemsData = [0 1];
            app.ExponenteSwitch.Value = 0;
            app.Exponente2SwitchLabel = uilabel(app.GeneradordesealesTab);
            app.Exponente2SwitchLabel.Text = 'Exponente 2';
            app.Exponente2Switch = uiswitch(app.GeneradordesealesTab, 'slider');
            app.Exponente2Switch.Items = {'-', '+'};
            app.Exponente2Switch.ItemsData = {'0', '1'};
            app.Exponente2Switch.Value = '0';
            app.Grafica2DropDownLabel = uilabel(app.GeneradordesealesTab);
            app.Grafica2DropDownLabel.Text = 'Grafica2';
            app.Grafica2DropDown = uidropdown(app.GeneradordesealesTab);
            app.Grafica2DropDown.Items = {'Selecciona', 'Cuadrada', 'Diente de sierra', 'Senoidal', 'Exponencial', 'E. Compleja', 'Escalon', 'Impulso', 'Rampa', 'Rectangular', 'Triangular'};
            app.Grafica2DropDown.ItemsData = [0 1 2 3 4 5 6 7 8 9 10];
            app.Grafica2DropDown.ValueChangedFcn = createCallbackFcn(app, @Grafica2DropDownValueChanged2, true);
            app.Grafica2DropDown.Value = 0;
            app.FsEditFieldLabel = uilabel(app.GeneradordesealesTab);
            app.FsEditFieldLabel.Text = 'Fs';
            app.FsEditField = uieditfield(app.GeneradordesealesTab, 'numeric');
            app.F0EditFieldLabel = uilabel(app.GeneradordesealesTab);
            app.F0EditFieldLabel.Text = 'F0';
            app.F0EditField = uieditfield(app.GeneradordesealesTab, 'numeric');
            app.Base1EditFieldLabel = uilabel(app.GeneradordesealesTab);
            app.Base1EditFieldLabel.Text = 'Base1';
            app.Base1EditField = uieditfield(app.GeneradordesealesTab, 'numeric');
            app.Base2EditFieldLabel = uilabel(app.GeneradordesealesTab);
            app.Base2EditFieldLabel.Text = 'Base2';
            app.Base2EditField = uieditfield(app.GeneradordesealesTab, 'numeric');
            app.TiempoIniEditFieldLabel = uilabel(app.GeneradordesealesTab);
            app.TiempoIniEditFieldLabel.Text = 'Tiempo  Ini';
            app.TiempoIniEditField = uieditfield(app.GeneradordesealesTab, 'numeric');
            app.TiempoFinEditFieldLabel = uilabel(app.GeneradordesealesTab);
            app.TiempoFinEditFieldLabel.Text = 'Tiempo  Fin';
            app.TiempoFinEditField = uieditfield(app.GeneradordesealesTab, 'numeric');
            app.Compleja1Switch = uiswitch(app.GeneradordesealesTab, 'slider');
            app.Compleja1Switch.Items = {'Img', 'R'};
            app.Compleja1Switch.ItemsData = {'0', '1'};
            app.Compleja1Switch.Value = '0';
            app.Compleja1SwitchLabel = uilabel(app.GeneradordesealesTab);
            app.Compleja1SwitchLabel.Text = 'Compleja 1';
            app.Compleja2Switch = uiswitch(app.GeneradordesealesTab, 'slider');
            app.Compleja2Switch.Items = {'Img', 'R'};
            app.Compleja2Switch.ItemsData = {'0', '1'};
            app.Compleja2Switch.Value = '0';
            app.Compleja2SwitchLabel = uilabel(app.GeneradordesealesTab);
            app.Compleja2SwitchLabel.Text = 'Compleja 2';
            app.Amplitud2EditFieldLabel = uilabel(app.GeneradordesealesTab);
            app.Amplitud2EditFieldLabel.Text = 'Amplitud2';
            app.Amplitud2EditField = uieditfield(app.GeneradordesealesTab, 'numeric');
            app.Amplitud1EditFieldLabel = uilabel(app.GeneradordesealesTab);
            app.Amplitud1EditFieldLabel.Text = 'Amplitud1';
            app.Amplitud1EditField = uieditfield(app.GeneradordesealesTab, 'numeric');
            app.Ancho1EditFieldLabel = uilabel(app.GeneradordesealesTab);
            app.Ancho1EditFieldLabel.Text = 'Ancho1';
            app.Ancho1EditField = uieditfield(app.GeneradordesealesTab, 'numeric');
            app.Desfase1EditFieldLabel = uilabel(app.GeneradordesealesTab);
            app.Desfase1EditFieldLabel.Text = 'Desfase1';
            app.Desfase1EditField = uieditfield(app.GeneradordesealesTab, 'numeric');
            app.Desfase1EditField.ValueChangedFcn = createCallbackFcn(app, @Desfase1EditFieldValueChanged, true);
            app.Ancho2EditFieldLabel = uilabel(app.GeneradordesealesTab);
            app.Ancho2EditFieldLabel.Text = 'Ancho2';
            app.Ancho2EditField = uieditfield(app.GeneradordesealesTab, 'numeric');
            app.Desfase2EditFieldLabel = uilabel(app.GeneradordesealesTab);
            app.Desfase2EditFieldLabel.Text = 'Desfase2';
            app.Desfase2EditField = uieditfield(app.GeneradordesealesTab, 'numeric');
            app.Ciclo1EditFieldLabel = uilabel(app.GeneradordesealesTab);
            app.Ciclo1EditFieldLabel.Text = 'Ciclo1';
            app.Ciclo1EditField = uieditfield(app.GeneradordesealesTab, 'numeric');
            app.Ciclo2EditFieldLabel = uilabel(app.GeneradordesealesTab);
            app.Ciclo2EditFieldLabel.Text = 'Ciclo2';
            app.Ciclo2EditField = uieditfield(app.GeneradordesealesTab, 'numeric');
            app.NaturalezaSwitchLabel = uilabel(app.GeneradordesealesTab);
            app.NaturalezaSwitchLabel.Text = 'Naturaleza';
            app.NaturalezaSwitch = uiswitch(app.GeneradordesealesTab, 'slider');
            app.NaturalezaSwitch.Items = {'Continua', 'Discreta'};
            app.NaturalezaSwitch.ItemsData = [0 1];
            app.NaturalezaSwitch.Value = 0;
            app.Funcion1SwitchLabel = uilabel(app.GeneradordesealesTab);
            app.Funcion1SwitchLabel.Text = 'Funcion1';
            app.Funcion1Switch = uiswitch(app.GeneradordesealesTab, 'slider');
            app.Funcion1Switch.Items = {'Generar', 'Anterior'};
            app.Funcion1Switch.ItemsData = {'0', '1'};
            app.Funcion1Switch.ValueChangedFcn = createCallbackFcn(app, @Funcion1SwitchValueChanged, true);
            app.Funcion1Switch.Value = '0';
            app.Funcion2SwitchLabel = uilabel(app.GeneradordesealesTab);
            app.Funcion2SwitchLabel.Text = 'Funcion2';
            app.Funcion2Switch = uiswitch(app.GeneradordesealesTab, 'slider');
            app.Funcion2Switch.Items = {'Generar', 'Anterior'};
            app.Funcion2Switch.ItemsData = [0 1];
            app.Funcion2Switch.ValueChangedFcn = createCallbackFcn(app, @Funcion2SwitchValueChanged, true);
            app.Funcion2Switch.Value = 0;
            app.F02EditFieldLabel = uilabel(app.GeneradordesealesTab);
            app.F02EditFieldLabel.Text = 'F02';
            app.F02EditField = uieditfield(app.GeneradordesealesTab, 'numeric');
            app.Button2 = uibutton(app.GeneradordesealesTab, 'push');
            app.Button2.Text = 'Button2';
            app.VarIndButton = uibutton(app.GeneradordesealesTab, 'push');
            app.VarIndButton.ButtonPushedFcn = createCallbackFcn(app, @VarIndButtonPushed, true);
            app.VarIndButton.Text = 'VarInd';
            app.SealesintroducidasTab = uitab(app.TabGroup);
            app.SealesintroducidasTab.Title = 'Señales introducidas';
            app.UIAxes3 = uiaxes(app.SealesintroducidasTab);
            title(app.UIAxes3, 'Señal 1')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.SealesintroducidasTab);
            title(app.UIAxes4, 'Señal 2')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.SealescorrelacionadasTab = uitab(app.TabGroup);
            app.SealescorrelacionadasTab.Title = 'Señales correlacionadas';
            app.UIAxes6 = uiaxes(app.SealescorrelacionadasTab);
            title(app.UIAxes6, 'Señal 1 (Y)')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes7 = uiaxes(app.SealescorrelacionadasTab);
            title(app.UIAxes7, 'Señal 2 (H)')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            zlabel(app.UIAxes7, 'Z')
            app.Fs2EditFieldLabel = uilabel(app.UIFigure);
            app.Fs2EditFieldLabel.Text = 'Fs2';
            app.Fs2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Tiempoini2EditFieldLabel = uilabel(app.UIFigure);
            app.Tiempoini2EditFieldLabel.Text = 'Tiempo ini2';
            app.Tiempoini2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Tiempofin2EditFieldLabel = uilabel(app.UIFigure);
            app.Tiempofin2EditFieldLabel.Text = 'Tiempo fin2';
            app.Tiempofin2EditField = uieditfield(app.UIFigure, 'numeric');
            app.LagSpinnerLabel = uilabel(app.UIFigure);
            app.LagSpinnerLabel.Text = 'Lag';
            app.LagSpinner = uispinner(app.UIFigure);
            app.LagSpinner.Limits = [0 Inf];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Practica_8_si

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end