classdef Proyecto_Final_bien < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        LoadButton    matlab.ui.control.Button
        GrabandoL     matlab.ui.control.Label
        Cancion_name  matlab.ui.control.Label
        CancinLabel   matlab.ui.control.Label
        Button        matlab.ui.control.Button
        Image         matlab.ui.control.Image
    end

    properties(Access=public)
        num_canciones
        lista_canciones
    end
       

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.GrabandoL.Text="Grabando...";
            fs=48000;
            duracion = 7; % Duración de la grabación en segu
            % Crear el objeto de grabación
            recorderObj = audiorecorder(fs, 16, 1);
      
            % Iniciar la grabación
            recordblocking(recorderObj, duracion);
        
            % Obtener los datos grabados
            audioData = getaudiodata(recorderObj);
        
            % Detener y restablecer el objeto de grabación
            stop(recorderObj);
        % Mostrar información en la etiqueta
            app.GrabandoL.Visible="off";
            app.GrabandoL.Text = "GRABACION COMPLETA";
            app.GrabandoL.Visible="on";


        % Aquí puedes procesar o guardar los datos grabados (audioData)

         % Por ejemplo, un filtro pasa bajos
            fc = 4000; % Frecuencia de corte del filtro
            orden = 5;% Orden del filtro
            filtroPasaBajos = designfilt('lowpassfir', 'CutoffFrequency', fc, 'FilterOrder', orden, 'SampleRate', fs);
            audioFiltrado = filter(filtroPasaBajos, audioData);
            freq_audio=real(fft(audioFiltrado));
        %audioFiltrado=2.*audioFiltrado;

        %app.lista_canciones
        song_index=0;
        fragment_index=0;
        
        canciones=app.lista_canciones;
        matrix_data=[];
        for i=1:app.num_canciones
            for j=1:canciones{i}.num_fragment
                [current_corr,des]=xcorr(freq_audio,canciones{i}.matrix_fragment_fft{j},"normalized");
                max_value=max(current_corr);
                max_freq_graph=current_corr;
                %disp(class(canciones))
                %max_value=canciones{i}.CrCorr(j,freq_audio);
                matrix_data=[matrix_data;i,j,max_value];
            end

        end
        ordered_matrix=sortrows(matrix_data,3,'descend');
        max_corr=0;

        for i=1:10
            l=ordered_matrix(i,1);
            j=ordered_matrix(i,2);
            [current_cor,des]=xcorr(audioFiltrado,canciones{l}.matrix_fragment{j},"normalized");
            max_value=max(current_cor);
            if abs(max_corr)<abs(max_value)
                song_index=l;
                fragment_index=j;
                max_corr=max_value;
                max_graph_corr=current_cor;
            end
        end


        app.Cancion_name.Text=canciones{song_index}.name;
        sound(audioFiltrado,fs);
        pause(7.2);
        sound(canciones{song_index}.matrix_fragment{fragment_index},app.lista_canciones{song_index}.fs_song);
        %pause(canciones{song_index}.frag_time+.2);
        
        
        figure;

        subplot(3, 2, 1);
        plot(canciones{song_index}.matrix_fragment{fragment_index})
        title("Fragmento")

        subplot(3, 2, 2);
        plot(canciones{song_index}.matrix_fragment_fft{fragment_index})
        title("Fragmento en frecuencia")
        
        subplot(3, 2, 3);
        plot(audioFiltrado)
        title("Audio")
        
        subplot(3, 2, 4);
        plot(freq_audio)
        title("Audio en frecuencia")

        subplot(3,2,5);
        plot(max_graph_corr)
        title('correlacion tiempo')

        subplot(3,2,6);
        plot(max_freq_graph)
        title("correlacion freq")

    
        % Ruta de la carpeta con las canciones


        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            import Cancion.*
            ruta_canciones = 'D:\Repositorios\Canciones';%Path de canciones
            archivos_canciones = dir(fullfile(ruta_canciones, '*.wav'));
            
            app.num_canciones=length(archivos_canciones);
            app.lista_canciones=cell(1,app.num_canciones);
            for k=1:app.num_canciones
            app.lista_canciones{k}=Cancion(archivos_canciones(k).name,ruta_canciones,7);
            end
            app.LoadButton.Visible="off";
            

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'gato.jpeg');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.CancinLabel = uilabel(app.UIFigure);
            app.CancinLabel.Text = 'Canción:';
            app.Cancion_name = uilabel(app.UIFigure);
            app.Cancion_name.Text = '';
            app.GrabandoL = uilabel(app.UIFigure);
            app.GrabandoL.Text = '';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Proyecto_Final_bien

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end