classdef proyecto_final_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ReproducirfragmentoCheckBox    matlab.ui.control.CheckBox
        Label                          matlab.ui.control.Label
        CANCIONLabel                   matlab.ui.control.Label
        GRABARLabel                    matlab.ui.control.Label
        Button                         matlab.ui.control.Button
        IDENTIFICADORDECANCIONESLabel  matlab.ui.control.Label
        Image                          matlab.ui.control.Image
    end

    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
 % Configuración de la grabación
        app.GRABARLabel.Text = 'Grabando...';
        chk_fragmento = app.ReproducirfragmentoCheckBox.Value;
        fs = 44100; % Frecuencia de muestreo en Hz
        duracion = 7; % Duración de la grabación en segundos

        % Crear el objeto de grabación
        recorderObj = audiorecorder(fs, 16, 1);
      
        % Iniciar la grabación
        recordblocking(recorderObj, duracion);
        
        % Obtener los datos grabados
        audioData = getaudiodata(recorderObj);
        
        % Detener y restablecer el objeto de grabación
        stop(recorderObj);
        % Mostrar información en la etiqueta
        app.GRABARLabel.Visible="off";
        app.GRABARLabel.Text = "GRABACION COMPLETA";
        app.GRABARLabel.Visible="on";


        % Aquí puedes procesar o guardar los datos grabados (audioData)

         % Por ejemplo, un filtro pasa bajos
        fc = 4000; % Frecuencia de corte del filtro
        orden = 5;% Orden del filtro
        filtroPasaBajos = designfilt('lowpassfir', 'CutoffFrequency', fc, 'FilterOrder', orden, 'SampleRate', fs);
        audioFiltrado = filter(filtroPasaBajos, audioData);
        %audioFiltrado=2.*audioFiltrado;


        % Ruta de la carpeta con las canciones
        ruta_canciones = 'D:\Repositorios\Procesamiento_de_senales\PF';
        % Obtener la lista de archivos de canciones en la carpeta
        archivos_canciones = dir(fullfile(ruta_canciones, '*.wav'));


mejor_cor = 0;
for i = 1:length(archivos_canciones)
max_cor = 0;
[cancion, fs_cancion] = audioread(fullfile(ruta_canciones, archivos_canciones(i).name));
tiempo = size(cancion,1)/fs_cancion;
segmentos = floor((tiempo / duracion));
lista_fragmentos = cell(1,segmentos);
for j=1:segmentos
   muestras = fs*duracion;
   if j ==1 
    s_aco = cancion(1:muestras);
   else 
    s_aco = cancion(muestras*(j-1):muestras*j);
   end
    lista_fragmentos{j} = s_aco;
    % Almacena el segmento en el cell array
    % Calcular la correlación cruzada
    [correlacion, desplazamientos] = xcorr(audioFiltrado, s_aco);
    % Encontrar el valor máximo de la correlación y su índice
    [max_correlacion_actual, indice_max_correlacion] = max(correlacion);
    
    % Actualizar la mejor correlación y el índice del segmento correlacionado si es necesario
    if abs(max_correlacion_actual) > abs(max_cor)
        max_cor = max_correlacion_actual;
    
        
    end

end

if abs(max_cor) > abs(mejor_cor)
    mejor_cor = max_cor;
    cancion_mejor = archivos_canciones(i).name;
    [max_cancion, fs_cancion] = audioread(fullfile(ruta_canciones, cancion_mejor));
end

end


app.Label.Text = string(cancion_mejor);

 sound(audioFiltrado,fs)
 pause(8)
 fragment=max_cancion(5*fs_cancion:10*fs_cancion);
 sound(fragment, fs_cancion)
 %pause(5)
 

        end

        % Value changed function: ReproducirfragmentoCheckBox
        function ReproducirfragmentoCheckBoxValueChanged(app, event)
            value = app.ReproducirfragmentoCheckBox.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'gato.jpg');
            app.IDENTIFICADORDECANCIONESLabel = uilabel(app.UIFigure);
            app.IDENTIFICADORDECANCIONESLabel.Text = 'IDENTIFICADOR DE CANCIONES';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '';
            app.Button.Icon = fullfile(pathToMLAPP, 'icone-de-microphone-rouge.png');
            app.GRABARLabel = uilabel(app.UIFigure);
            app.GRABARLabel.Text = 'GRABAR';
            app.CANCIONLabel = uilabel(app.UIFigure);
            app.CANCIONLabel.Text = 'CANCION:';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.ReproducirfragmentoCheckBox = uicheckbox(app.UIFigure);
            app.ReproducirfragmentoCheckBox.ValueChangedFcn = createCallbackFcn(app, @ReproducirfragmentoCheckBoxValueChanged, true);
            app.ReproducirfragmentoCheckBox.Text = 'Reproducir fragmento';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = proyecto_final_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end